classdef ADCmapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ADCmapUIFigure           matlab.ui.Figure
        TabGroup4                matlab.ui.container.TabGroup
        SequenceparametersTab    matlab.ui.container.Tab
        DicomDirViewField        matlab.ui.control.EditField
        DicomdirEditFieldLabel   matlab.ui.control.Label
        AspectRatioViewField     matlab.ui.control.NumericEditField
        AspectratioLabel         matlab.ui.control.Label
        SLTViewField             matlab.ui.control.NumericEditField
        SlicethicknessmmLabel    matlab.ui.control.Label
        FOVViewField             matlab.ui.control.NumericEditField
        FOVmmEditFieldLabel      matlab.ui.control.Label
        NRImagesViewField        matlab.ui.control.NumericEditField
        ImagesLabel              matlab.ui.control.Label
        SlicesViewField          matlab.ui.control.NumericEditField
        SlicesEditFieldLabel     matlab.ui.control.Label
        dimyViewField            matlab.ui.control.NumericEditField
        dimyEditFieldLabel       matlab.ui.control.Label
        dimxViewField            matlab.ui.control.NumericEditField
        dimxEditFieldLabel       matlab.ui.control.Label
        TEViewField              matlab.ui.control.NumericEditField
        TEmsLabel                matlab.ui.control.Label
        TRViewField              matlab.ui.control.NumericEditField
        TRmsEditFieldLabel       matlab.ui.control.Label
        TabGroup2                matlab.ui.container.TabGroup
        RawimagesTab             matlab.ui.container.Tab
        GradViewField            matlab.ui.control.EditField
        GradEditFieldLabel       matlab.ui.control.Label
        BvalueViewField          matlab.ui.control.EditField
        BvalueEditFieldLabel     matlab.ui.control.Label
        RawImage                 matlab.ui.control.Image
        ImageIntensityPanel      matlab.ui.container.Panel
        ScaleSlider              matlab.ui.control.Slider
        ScaleSliderLabel         matlab.ui.control.Label
        SegmentationPanel        matlab.ui.container.Panel
        ThresholdEditField       matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel  matlab.ui.control.Label
        OpenButton               matlab.ui.control.Button
        CloseButton              matlab.ui.control.Button
        DilateButton             matlab.ui.control.Button
        ErodeButton              matlab.ui.control.Button
        ThresholdSlider          matlab.ui.control.Slider
        ImageselectorPanel       matlab.ui.container.Panel
        ImageEditField           matlab.ui.control.NumericEditField
        ImageLabel_2             matlab.ui.control.Label
        SlicesEditField          matlab.ui.control.NumericEditField
        SliceEditFieldLabel      matlab.ui.control.Label
        ImageSlider              matlab.ui.control.Slider
        SlicesSlider             matlab.ui.control.Slider
        ImageLabel               matlab.ui.control.Label
        RawDataFig               matlab.ui.control.UIAxes
        FitparametersTab         matlab.ui.container.Tab
        UsergradienttablePanel   matlab.ui.container.Panel
        ImportGradTableButton    matlab.ui.control.Button
        BvaluegradientdirectionselectionPanel  matlab.ui.container.Panel
        CheckBox_64              matlab.ui.control.CheckBox
        CheckBox_63              matlab.ui.control.CheckBox
        CheckBox_62              matlab.ui.control.CheckBox
        CheckBox_61              matlab.ui.control.CheckBox
        CheckBox_60              matlab.ui.control.CheckBox
        CheckBox_59              matlab.ui.control.CheckBox
        CheckBox_58              matlab.ui.control.CheckBox
        CheckBox_57              matlab.ui.control.CheckBox
        CheckBox_56              matlab.ui.control.CheckBox
        CheckBox_55              matlab.ui.control.CheckBox
        CheckBox_54              matlab.ui.control.CheckBox
        CheckBox_53              matlab.ui.control.CheckBox
        CheckBox_52              matlab.ui.control.CheckBox
        CheckBox_51              matlab.ui.control.CheckBox
        CheckBox_50              matlab.ui.control.CheckBox
        CheckBox_49              matlab.ui.control.CheckBox
        CheckBox_48              matlab.ui.control.CheckBox
        CheckBox_47              matlab.ui.control.CheckBox
        CheckBox_46              matlab.ui.control.CheckBox
        CheckBox_45              matlab.ui.control.CheckBox
        CheckBox_44              matlab.ui.control.CheckBox
        CheckBox_43              matlab.ui.control.CheckBox
        CheckBox_42              matlab.ui.control.CheckBox
        CheckBox_41              matlab.ui.control.CheckBox
        CheckBox_40              matlab.ui.control.CheckBox
        CheckBox_39              matlab.ui.control.CheckBox
        CheckBox_38              matlab.ui.control.CheckBox
        CheckBox_37              matlab.ui.control.CheckBox
        CheckBox_36              matlab.ui.control.CheckBox
        CheckBox_35              matlab.ui.control.CheckBox
        CheckBox_34              matlab.ui.control.CheckBox
        CheckBox_33              matlab.ui.control.CheckBox
        CheckBox_32              matlab.ui.control.CheckBox
        CheckBox_31              matlab.ui.control.CheckBox
        CheckBox_30              matlab.ui.control.CheckBox
        CheckBox_29              matlab.ui.control.CheckBox
        CheckBox_28              matlab.ui.control.CheckBox
        CheckBox_27              matlab.ui.control.CheckBox
        CheckBox_26              matlab.ui.control.CheckBox
        CheckBox_25              matlab.ui.control.CheckBox
        CheckBox_24              matlab.ui.control.CheckBox
        CheckBox_23              matlab.ui.control.CheckBox
        CheckBox_22              matlab.ui.control.CheckBox
        CheckBox_21              matlab.ui.control.CheckBox
        CheckBox_20              matlab.ui.control.CheckBox
        CheckBox_19              matlab.ui.control.CheckBox
        CheckBox_18              matlab.ui.control.CheckBox
        CheckBox_17              matlab.ui.control.CheckBox
        CheckBox_16              matlab.ui.control.CheckBox
        CheckBox_15              matlab.ui.control.CheckBox
        CheckBox_14              matlab.ui.control.CheckBox
        CheckBox_13              matlab.ui.control.CheckBox
        CheckBox_12              matlab.ui.control.CheckBox
        CheckBox_11              matlab.ui.control.CheckBox
        CheckBox_10              matlab.ui.control.CheckBox
        CheckBox_9               matlab.ui.control.CheckBox
        CheckBox_8               matlab.ui.control.CheckBox
        CheckBox_7               matlab.ui.control.CheckBox
        CheckBox_6               matlab.ui.control.CheckBox
        CheckBox_5               matlab.ui.control.CheckBox
        CheckBox_4               matlab.ui.control.CheckBox
        CheckBox_3               matlab.ui.control.CheckBox
        CheckBox_2               matlab.ui.control.CheckBox
        CheckBox_1               matlab.ui.control.CheckBox
        GoodnessoffitPanel       matlab.ui.container.Panel
        ThresholdLabel           matlab.ui.control.Label
        ThresholdR               matlab.ui.control.CheckBox
        Rsquare                  matlab.ui.control.NumericEditField
        RsquareLabel             matlab.ui.control.Label
        SliceselectionPanel      matlab.ui.container.Panel
        LastSliceEditField       matlab.ui.control.NumericEditField
        LastsliceLabel           matlab.ui.control.Label
        FirstSliceEditField      matlab.ui.control.NumericEditField
        FirstsliceLabel          matlab.ui.control.Label
        TabGroup3                matlab.ui.container.TabGroup
        ADCmapTab                matlab.ui.container.Tab
        ADCunitLabel3            matlab.ui.control.Label
        ADCunitLabel2            matlab.ui.control.Label
        ADCunitLabel1            matlab.ui.control.Label
        ADCLabel                 matlab.ui.control.Label
        ADCViewField             matlab.ui.control.NumericEditField
        YEditField               matlab.ui.control.NumericEditField
        YEditFieldLabel          matlab.ui.control.Label
        XEditField               matlab.ui.control.NumericEditField
        XEditFieldLabel          matlab.ui.control.Label
        ADCImage                 matlab.ui.control.Image
        ColorMapButtonGroup      matlab.ui.container.ButtonGroup
        ADCScaleAutoButton       matlab.ui.control.Button
        ADCScaleMaxLabel         matlab.ui.control.Label
        ADCScaleEditField        matlab.ui.control.NumericEditField
        hotButton                matlab.ui.control.RadioButton
        parulaButton             matlab.ui.control.RadioButton
        winterButton             matlab.ui.control.RadioButton
        autumnButton             matlab.ui.control.RadioButton
        grayButton               matlab.ui.control.RadioButton
        ImageSelectorPanel       matlab.ui.container.Panel
        ADCMapSlicesEditField    matlab.ui.control.NumericEditField
        SliceEditField_2Label    matlab.ui.control.Label
        ADCMapSlicesSlider       matlab.ui.control.Slider
        ADCMapLabel              matlab.ui.control.Label
        ADClinearPlot            matlab.ui.control.UIAxes
        ADClogPlot               matlab.ui.control.UIAxes
        ADCMapFig                matlab.ui.control.UIAxes
        M0R2Tab                  matlab.ui.container.Tab
        R2Image                  matlab.ui.control.Image
        M0Image                  matlab.ui.control.Image
        ColorMapButtonGroup3     matlab.ui.container.ButtonGroup
        hotButton3               matlab.ui.control.RadioButton
        parulaButton3            matlab.ui.control.RadioButton
        winterButton3            matlab.ui.control.RadioButton
        autumnButton3            matlab.ui.control.RadioButton
        grayButton3              matlab.ui.control.RadioButton
        ColorMapButtonGroup2     matlab.ui.container.ButtonGroup
        hotButton2               matlab.ui.control.RadioButton
        parulaButton2            matlab.ui.control.RadioButton
        winterButton2            matlab.ui.control.RadioButton
        autumnButton2            matlab.ui.control.RadioButton
        grayButton2              matlab.ui.control.RadioButton
        ImageSelectorPanel2      matlab.ui.container.Panel
        ADCMapSlicesEditField2   matlab.ui.control.NumericEditField
        SliceEditField_2Label_2  matlab.ui.control.Label
        ADCMapSlicesSlider2      matlab.ui.control.Slider
        R2MapLabel               matlab.ui.control.Label
        M0MapLabel               matlab.ui.control.Label
        R2MapFig                 matlab.ui.control.UIAxes
        M0MapFig                 matlab.ui.control.UIAxes
        TabGroup1                matlab.ui.container.TabGroup
        DashboardTab             matlab.ui.container.Tab
        RegProgressLabel         matlab.ui.control.Label
        RegProgressGauge         matlab.ui.control.LinearGauge
        Lamp2                    matlab.ui.control.Lamp
        RegistrationButton       matlab.ui.control.Button
        AbortFitButton           matlab.ui.control.Button
        ExportGifButton          matlab.ui.control.Button
        Lamp6                    matlab.ui.control.Lamp
        FitProgressLabel         matlab.ui.control.Label
        ExportDicomButton        matlab.ui.control.Button
        ExitButton               matlab.ui.control.Button
        Lamp5                    matlab.ui.control.Lamp
        FitProgressGauge         matlab.ui.control.LinearGauge
        Lamp4                    matlab.ui.control.Lamp
        ADCFitButton             matlab.ui.control.Button
        Lamp3                    matlab.ui.control.Lamp
        ThresholdButton          matlab.ui.control.Button
        Lamp1                    matlab.ui.control.Lamp
        LoadMRDfileButton        matlab.ui.control.Button
        MRSImage                 matlab.ui.control.Image
        statusLabel              matlab.ui.control.Label
        StatusImage              matlab.ui.control.Image
        BackgroundBox            matlab.ui.control.TextArea
        MessageBox               matlab.ui.control.TextArea
        TulpLogo                 matlab.ui.control.Image
        Credits                  matlab.ui.control.Label
        ADCmappingLabel          matlab.ui.control.Label
    end


    %% --------------------------------------------------------------------------------------
    %
    %   ADC mapping tool
    %    
    %
    %   Gustav Strijkers
    %   Amsterdam UMC
    %   Sept 2023
    %
    %
    %   
    %
    %
    %   Version 1.0; nov 2020
    %   - intial version
    %
    %   Version 1.1; April 2021
    %   - bug fix in image aspect ratio
    %
    %
    %   Version 2.0
    %
    %% --------------------------------------------------------------------------------------


    properties (Access = public)

        appVersion = "2.0.2"        % App version
       
        % file variables
        appdir                      % Application directory
        olddir                      % Starting directory
        dicomImportPath             % Dicom import path
        dicomExportPath             % Dicom export path
        gradPath                    % Gradient file import path
        gifExportPath               % gif export path
        dataFile                    % raw data file
        data                        % raw k-space data
        tag                         % file tag for export of dicom files
        dcmInfo                     % Dicom info (tags)
        
        % checks
        validData_flag              % valid data file true/false
        validReco_flag              % valid reco true/false
        validFit_flag               % valid fit true/false
        
        % scan properties
        dimensions                  % image dimensions
        parameters                  % image paramters
        binfo                       % info on b-value and diffusion directions
        bValueInfo                  % info on b-value and diffusion directions, cell array
        nr                          % number of images
        dimx                        % nr points readout
        dimy                        % nt points phase encoding
        fov                         % field of view
        aspectRatio                 % field of view ratio
        sl                          % slice thickness
        ns                          % number of slices
        tr                          % repetition time
        te                          % echo time / spacing
        gradTable                   % gradient table
      
        % images
        images                      % reconstructed raw images (ne, ns, dimx, dimy)
        threshold                   % segmentation threshold (ns,1)
        mask                        % binary image with segmentation (ns, dimx, dimy)
        scalefactor                 % image intensity scale factor
        txt1                        % legend label     
        txt2                        % legend label  
     
        % fitting
        adcmap                      % resulting ADCmap (ns, dimx, dimy)
        adccmap                     % ADC color mapping
        m0map                       % resulting m0map (ns, dimx, dimy)
        m0cmap                      % M0 color mapping
        r2map                       % resulting R2 map
        r2cmap                      % R2 color mapping
        nr_selection                % Image (b-value / gradient dir) selection array
        abortfit                    % abort fit false or true
        
        % colors
        brightred                   % red
        red                         % red
        brightgreen                 % green
        green                       % green
        yellow                      % yellow
        orange                      % orange
        blue                        % blue
        grey                        % grey


    end
    
    
    methods (Access = private)


        %------------------------------------------------
        %
        %  GUI layout
        %
        %------------------------------------------------


        % Traffic lights
        function SetLights(app,code)
            
            if code(1) == 0 app.Lamp1.Color = app.red; end %#ok<*SEPEX>
            if code(2) == 0 app.Lamp2.Color = app.red; end
            if code(3) == 0 app.Lamp3.Color = app.red; end
            if code(4) == 0 app.Lamp4.Color = app.red; end
            if code(5) == 0 app.Lamp5.Color = app.red; end
            if code(6) == 0 app.Lamp6.Color = app.red; end
                
            if code(1) == 1 app.Lamp1.Color = app.green; end
            if code(2) == 1 app.Lamp2.Color = app.green; end
            if code(3) == 1 app.Lamp3.Color = app.green; end
            if code(4) == 1 app.Lamp4.Color = app.green; end
            if code(5) == 1 app.Lamp5.Color = app.green; end
            if code(6) == 1 app.Lamp6.Color = app.green; end
            
            drawnow;
            
        end
        
        
        % Buttons on/off
        function SetButtons(app,code)
        
            if code(1) == 0 app.LoadMRDfileButton.Enable = 'off';   else app.LoadMRDfileButton.Enable = 'on';   end
            if code(2) == 0 app.RegistrationButton.Enable = 'off';  else app.RegistrationButton.Enable = 'on';  end
            if code(3) == 0 app.ThresholdButton.Enable = 'off';     else app.ThresholdButton.Enable = 'on';     end
            if code(4) == 0 app.ADCFitButton.Enable = 'off';         else app.ADCFitButton.Enable = 'on';         end
            if code(5) == 0 app.ExportDicomButton.Enable = 'off';   else app.ExportDicomButton.Enable = 'on';   end
            if code(6) == 0 app.ExportGifButton.Enable = 'off';     else app.ExportGifButton.Enable = 'on';     end
            if code(7) == 0 app.ExitButton.Enable = 'off';          else app.ExitButton.Enable = 'on';          end
        
            drawnow;
            
        end
        
        
        % Switch fit parameters buttons on/off
        function SetFitButtons(app,code)
            
            if code == 0
                
                app.FirstSliceEditField.Enable = 'off';
                app.LastSliceEditField.Enable = 'off';
                app.Rsquare.Enable = 'off';
                app.AbortFitButton.Enable = 'off';
                app.ThresholdR.Enable = 'off';
                app.ImportGradTableButton.Enable = 'off';
                
                app.CheckBox_1.Enable = 'off';  app.CheckBox_2.Enable = 'off';  app.CheckBox_3.Enable = 'off';  app.CheckBox_4.Enable = 'off';
                app.CheckBox_5.Enable = 'off';  app.CheckBox_6.Enable = 'off';  app.CheckBox_7.Enable = 'off';  app.CheckBox_8.Enable = 'off';
                app.CheckBox_9.Enable = 'off';  app.CheckBox_10.Enable = 'off'; app.CheckBox_11.Enable = 'off'; app.CheckBox_12.Enable = 'off';
                app.CheckBox_13.Enable = 'off'; app.CheckBox_14.Enable = 'off'; app.CheckBox_15.Enable = 'off'; app.CheckBox_16.Enable = 'off';
                app.CheckBox_17.Enable = 'off'; app.CheckBox_18.Enable = 'off'; app.CheckBox_19.Enable = 'off'; app.CheckBox_20.Enable = 'off';
                app.CheckBox_21.Enable = 'off'; app.CheckBox_22.Enable = 'off'; app.CheckBox_23.Enable = 'off'; app.CheckBox_24.Enable = 'off';
                app.CheckBox_25.Enable = 'off'; app.CheckBox_26.Enable = 'off'; app.CheckBox_27.Enable = 'off'; app.CheckBox_28.Enable = 'off';
                app.CheckBox_29.Enable = 'off'; app.CheckBox_30.Enable = 'off'; app.CheckBox_31.Enable = 'off'; app.CheckBox_32.Enable = 'off';
                app.CheckBox_33.Enable = 'off'; app.CheckBox_34.Enable = 'off'; app.CheckBox_35.Enable = 'off'; app.CheckBox_36.Enable = 'off';
                app.CheckBox_37.Enable = 'off'; app.CheckBox_38.Enable = 'off'; app.CheckBox_39.Enable = 'off'; app.CheckBox_40.Enable = 'off';
                app.CheckBox_41.Enable = 'off'; app.CheckBox_42.Enable = 'off'; app.CheckBox_43.Enable = 'off'; app.CheckBox_44.Enable = 'off';
                app.CheckBox_45.Enable = 'off'; app.CheckBox_46.Enable = 'off'; app.CheckBox_47.Enable = 'off'; app.CheckBox_48.Enable = 'off';
                app.CheckBox_49.Enable = 'off'; app.CheckBox_50.Enable = 'off'; app.CheckBox_51.Enable = 'off'; app.CheckBox_52.Enable = 'off';
                app.CheckBox_53.Enable = 'off'; app.CheckBox_54.Enable = 'off'; app.CheckBox_55.Enable = 'off'; app.CheckBox_56.Enable = 'off';
                app.CheckBox_57.Enable = 'off'; app.CheckBox_58.Enable = 'off'; app.CheckBox_59.Enable = 'off'; app.CheckBox_60.Enable = 'off';
                app.CheckBox_61.Enable = 'off'; app.CheckBox_62.Enable = 'off'; app.CheckBox_63.Enable = 'off'; app.CheckBox_64.Enable = 'off';
             
            else
            
                app.FirstSliceEditField.Enable = 'on';
                app.LastSliceEditField.Enable = 'on';
                app.Rsquare.Enable = 'on';
                app.AbortFitButton.Enable = 'on';
                app.ThresholdR.Enable = 'on';
                app.ImportGradTableButton.Enable = 'on';
                
                app.CheckBox_1.Enable = 'on';
                if app.nr > 1 app.CheckBox_2.Enable = 'on'; end;        if app.nr > 32 app.CheckBox_33.Enable = 'on'; end
                if app.nr > 2 app.CheckBox_3.Enable = 'on'; end;        if app.nr > 33 app.CheckBox_34.Enable = 'on'; end
                if app.nr > 3 app.CheckBox_4.Enable = 'on'; end;        if app.nr > 34 app.CheckBox_35.Enable = 'on'; end
                if app.nr > 4 app.CheckBox_5.Enable = 'on'; end;        if app.nr > 35 app.CheckBox_36.Enable = 'on'; end
                if app.nr > 5 app.CheckBox_6.Enable = 'on'; end;        if app.nr > 36 app.CheckBox_37.Enable = 'on'; end
                if app.nr > 6 app.CheckBox_7.Enable = 'on'; end;        if app.nr > 37 app.CheckBox_38.Enable = 'on'; end
                if app.nr > 7 app.CheckBox_8.Enable = 'on'; end;        if app.nr > 38 app.CheckBox_39.Enable = 'on'; end
                if app.nr > 8 app.CheckBox_9.Enable = 'on'; end;        if app.nr > 39 app.CheckBox_40.Enable = 'on'; end
                if app.nr > 9 app.CheckBox_10.Enable = 'on'; end;       if app.nr > 40 app.CheckBox_41.Enable = 'on'; end
                if app.nr > 10 app.CheckBox_11.Enable = 'on'; end;      if app.nr > 41 app.CheckBox_42.Enable = 'on'; end
                if app.nr > 11 app.CheckBox_12.Enable = 'on'; end;      if app.nr > 42 app.CheckBox_43.Enable = 'on'; end
                if app.nr > 12 app.CheckBox_13.Enable = 'on'; end;      if app.nr > 43 app.CheckBox_44.Enable = 'on'; end
                if app.nr > 13 app.CheckBox_14.Enable = 'on'; end;      if app.nr > 44 app.CheckBox_45.Enable = 'on'; end
                if app.nr > 14 app.CheckBox_15.Enable = 'on'; end;      if app.nr > 45 app.CheckBox_46.Enable = 'on'; end
                if app.nr > 15 app.CheckBox_16.Enable = 'on'; end;      if app.nr > 46 app.CheckBox_47.Enable = 'on'; end
                if app.nr > 16 app.CheckBox_17.Enable = 'on'; end;      if app.nr > 47 app.CheckBox_48.Enable = 'on'; end
                if app.nr > 17 app.CheckBox_18.Enable = 'on'; end;      if app.nr > 48 app.CheckBox_49.Enable = 'on'; end
                if app.nr > 18 app.CheckBox_19.Enable = 'on'; end;      if app.nr > 49 app.CheckBox_50.Enable = 'on'; end
                if app.nr > 19 app.CheckBox_20.Enable = 'on'; end;      if app.nr > 50 app.CheckBox_51.Enable = 'on'; end
                if app.nr > 20 app.CheckBox_21.Enable = 'on'; end;      if app.nr > 51 app.CheckBox_52.Enable = 'on'; end
                if app.nr > 21 app.CheckBox_22.Enable = 'on'; end;      if app.nr > 52 app.CheckBox_53.Enable = 'on'; end
                if app.nr > 22 app.CheckBox_23.Enable = 'on'; end;      if app.nr > 53 app.CheckBox_54.Enable = 'on'; end
                if app.nr > 23 app.CheckBox_24.Enable = 'on'; end;      if app.nr > 54 app.CheckBox_55.Enable = 'on'; end
                if app.nr > 24 app.CheckBox_25.Enable = 'on'; end;      if app.nr > 55 app.CheckBox_56.Enable = 'on'; end
                if app.nr > 25 app.CheckBox_26.Enable = 'on'; end;      if app.nr > 56 app.CheckBox_57.Enable = 'on'; end
                if app.nr > 26 app.CheckBox_27.Enable = 'on'; end;      if app.nr > 57 app.CheckBox_58.Enable = 'on'; end
                if app.nr > 27 app.CheckBox_28.Enable = 'on'; end;      if app.nr > 58 app.CheckBox_59.Enable = 'on'; end
                if app.nr > 28 app.CheckBox_29.Enable = 'on'; end;      if app.nr > 59 app.CheckBox_60.Enable = 'on'; end
                if app.nr > 29 app.CheckBox_30.Enable = 'on'; end;      if app.nr > 60 app.CheckBox_61.Enable = 'on'; end
                if app.nr > 30 app.CheckBox_31.Enable = 'on'; end;      if app.nr > 61 app.CheckBox_62.Enable = 'on'; end
                if app.nr > 31 app.CheckBox_32.Enable = 'on'; end;      if app.nr > 62 app.CheckBox_63.Enable = 'on'; end
                if app.nr > 63 app.CheckBox_64.Enable = 'on'; end
                
            end
                                   
            drawnow;
            
        end
        
        
        % Switch image selection buttons on/off
        function SetImageSelectionButtons(app,code)
            
            if code == 0
                app.SlicesEditField.Enable = 'off';
                app.SlicesSlider.Enable = 'off';
                app.ImageEditField.Enable = 'off';
                app.ImageSlider.Enable = 'off';
                app.ScaleSlider.Enable = 'off';
            else
                app.SlicesEditField.Enable = 'on';
                app.SlicesSlider.Enable = 'on';
                app.ImageEditField.Enable = 'on';
                app.ImageSlider.Enable = 'on';
                app.ScaleSlider.Enable = 'on';
            end
            
            drawnow;
            
        end
        
        
        % Switch image segmentation buttons on/off
        function SetSegmentationButtons(app,code)
            
            if code == 0
                app.ThresholdEditField.Enable = 'off';
                app.ThresholdSlider.Enable = 'off';
                app.ErodeButton.Enable = 'off';
                app.DilateButton.Enable = 'off';
                app.CloseButton.Enable = 'off';
                app.OpenButton.Enable = 'off';
            else
                app.ThresholdEditField.Enable = 'on';
                app.ThresholdSlider.Enable = 'on';
                app.ErodeButton.Enable = 'on';
                app.DilateButton.Enable = 'on';
                app.CloseButton.Enable = 'on';
                app.OpenButton.Enable = 'on';
            end
            
            drawnow;
            
        end
        
        
        % Switch ADC map buttons on/off
        function SetMapButtons(app,code)
            
            if code == 0
                app.ADCMapSlicesEditField.Enable = 'off';
                app.ADCMapSlicesSlider.Enable = 'off';
                app.ADCScaleEditField.Enable = 'off';
                app.ADCScaleAutoButton.Enable = 'off';
                app.grayButton.Enable = 'off';
                app.autumnButton.Enable = 'off';
                app.winterButton.Enable = 'off';
                app.parulaButton.Enable = 'off';
                app.hotButton.Enable = 'off';
                app.AbortFitButton.Enable = 'off';
            else
                app.ADCMapSlicesEditField.Enable = 'on';
                app.ADCMapSlicesSlider.Enable = 'on';
                app.ADCScaleEditField.Enable = 'on';
                app.ADCScaleAutoButton.Enable = 'on';
                app.grayButton.Enable = 'on';
                app.autumnButton.Enable = 'on';
                app.winterButton.Enable = 'on';
                app.parulaButton.Enable = 'on';
                app.hotButton.Enable = 'on';
                app.AbortFitButton.Enable = 'on';
            end
           
            drawnow;
            
        end
        
        
        % Initialize the GUI
        function InitializeUI(app)
            
            % Message
            app.TextMessage('Initializing UI ...');

            % Credits
            app.Credits.Text = strcat("Gustav Strijkers, Amsterdam UMC, Version ",app.appVersion);
            
            % Define app colors
            app.brightred = 'Red';
            app.red = '#D95319';
            app.brightgreen = 'Green'; 
            app.green = '#6CB557';
            app.yellow = 'Yellow';
            app.orange = '#FDA400';
            app.blue = '#0073BE';
            app.grey = '#787878';
            
            % Logo's
            app.TulpLogo.ImageSource = 'AmsterdamUMC.gif';
            app.MRSImage.ImageSource = 'MRS.gif';
            
            % Icons
            app.LoadMRDfileButton.Icon = 'disk.png';
            app.RegistrationButton.Icon = 'sort.gif';
            app.ThresholdButton.Icon = 'threshold.gif';
            app.ADCFitButton.Icon = 'exponential.gif';
            app.ExportDicomButton.Icon = 'disk.png';
            app.ExportGifButton.Icon = 'disk.png';
            app.ExitButton.Icon = 'exit.png';
            app.StatusImage.ImageSource = 'statusokay.gif';
            app.ADCImage.ImageSource = 'square.gif';
            app.RawImage.ImageSource = 'square.gif';
            app.M0Image.ImageSource = 'square.gif';
            app.R2Image.ImageSource = 'square.gif';
            
            % set lights and buttons
            app.SetLights([0,0,0,0,0,0]);
            app.SetButtons([1,0,0,0,0,0,1]);
            app.SetFitButtons(0);
            app.SetImageSelectionButtons(0);
            app.SetSegmentationButtons(0);
            app.SetMapButtons(0);
            
            % Set some plot properties
            app.RawDataFig.Toolbar.Visible = 'off';
            app.ADCMapFig.Toolbar.Visible = 'off';
            app.M0MapFig.Toolbar.Visible = 'off';
            app.ADClogPlot.Toolbar.Visible = 'off';
            app.ADClinearPlot.Toolbar.Visible = 'off';
            
            % Switch off plot interactivity
            app.RawDataFig.Interactions = [];
            app.ADCMapFig.Interactions = [];
            app.M0MapFig.Interactions = [];
            app.ADClogPlot.Interactions = [];
            app.ADClinearPlot.Interactions = [];
            
            drawnow;
            
        end


        %------------------------------------------------
        %
        %  Figures and Plots
        %
        %------------------------------------------------


        % Displays the mask in the raw image data
        function PlotRawImages(app)
          
            cla(app.RawDataFig);
            hold(app.RawDataFig,'on');
            
            % Define the colormap
            % Since it is not possible to have 2 colormaps in 1 figure, the
            % colormap is split in a grayscale part [0 1] and a red-color overlay part [1 2]
            cmap1 = 1.5*app.scalefactor*[linspace(0,1,128)',linspace(0,1,128)',linspace(0,1,128)'];
            cmap2 = [linspace(0,1,128)', zeros(128,2)];
            cmap = [cmap1;cmap2];
            cmap(cmap > 1) = 1;
                        
            % Background anatomy image
            scale = max(squeeze(app.images(1,app.SlicesEditField.Value,:)));
            [dx,dy] = size(squeeze(app.images(app.ImageEditField.Value,app.SlicesEditField.Value,:,:)));
            xlim(app.RawDataFig,[1,dx]);
            ylim(app.RawDataFig,[1,dy]);
            im = squeeze(app.images(app.ImageEditField.Value,app.SlicesEditField.Value,:,:))/scale;
            background = imshow(rot90(im),[0,2],'Colormap',cmap,'InitialMagnification','fit','Parent',app.RawDataFig); %#ok<NASGU>
            
            % Overlay image with the mask
            imo = squeeze(2-app.mask(app.SlicesEditField.Value,:,:));
            alpha = rot90(squeeze(0.7-0.7*app.mask(app.SlicesEditField.Value,:,:)));
            overlay = imshow(rot90(imo),[0 2],'Colormap',cmap,'InitialMagnification','fit','Parent',app.RawDataFig);
            set(overlay,'AlphaData',alpha);
            
            % Image aspect ratio
            daspect(app.RawDataFig,[app.aspectRatio 1 1]);
            
            hold(app.RawDataFig,'off');
            drawnow;
            
            % Set some limits now
            app.ThresholdEditField.Limits = [0 max(app.images(1,app.SlicesEditField.Value,:))+1];
            app.ThresholdEditField.Value = app.threshold(app.SlicesEditField.Value);                                                                       
            app.ThresholdSlider.Limits = [0 max(app.images(1,app.SlicesEditField.Value,:))+1];
            app.ThresholdSlider.Value = app.threshold(app.SlicesEditField.Value);
                        
        end % PlotRawImages
        
        
        % Plots the maps for the current slice
        function PlotMaps(app)
            
            hold(app.ADCMapFig,'on');
            hold(app.M0MapFig,'on');
            hold(app.R2MapFig,'on');
            
            % Plot the ADC map
            [dx,dy] = size(squeeze(app.adcmap(app.ADCMapSlicesEditField.Value,:,:)));
            xlim(app.ADCMapFig,[1,dx]);
            ylim(app.ADCMapFig,[1,dy]);
            im = squeeze(app.adcmap(app.ADCMapSlicesEditField.Value,:,:));
            
            imshow(rot90(im),[0 app.ADCScaleEditField.Value],'Colormap',app.adccmap,'InitialMagnification','fit','Parent',app.ADCMapFig);
            daspect(app.ADCMapFig,[app.aspectRatio 1 1]);
            colorbar(app.ADCMapFig,'east','Ticks',[]);
            
            % Estimate a suitable scale for the M0 map
            m0scale = round(2*mean(nonzeros(squeeze(app.m0map(app.ADCMapSlicesEditField.Value,:)))));
            m0scale(isnan(m0scale)) = 100;
            m0scale(m0scale<1) = 1;
            
            % Plot the M0 map
            [dx,dy] = size(squeeze(app.m0map(app.ADCMapSlicesEditField.Value,:,:)));
            xlim(app.M0MapFig,[1,dx]);
            ylim(app.M0MapFig,[1,dy]);
            im = squeeze(app.m0map(app.ADCMapSlicesEditField.Value,:,:));
            imshow(rot90(im),[0 m0scale],'Colormap',app.m0cmap,'InitialMagnification','fit','Parent',app.M0MapFig);
            daspect(app.M0MapFig,[app.aspectRatio 1 1]);
            
            % Plot the R2 map
            [dx,dy] = size(squeeze(app.r2map(app.ADCMapSlicesEditField.Value,:,:)));
            xlim(app.R2MapFig,[1,dx]);
            ylim(app.R2MapFig,[1,dy]);
            im = squeeze(app.r2map(app.ADCMapSlicesEditField.Value,:,:));
            imshow(rot90(im),[app.Rsquare.Value 1],'Colormap',app.r2cmap,'InitialMagnification','fit','Parent',app.R2MapFig);
            daspect(app.R2MapFig,[app.aspectRatio 1 1]);
            colorbar(app.R2MapFig,'east','Ticks',[app.Rsquare.Value, 1]);
            delete(app.txt1);
            delete(app.txt2);
            app.txt1 = text(app.R2MapFig,0.85,0.95,'1.0','Units','normalized','Color',app.grey,'FontSize',14);
            app.txt2 = text(app.R2MapFig,0.85,0.05,num2str(app.Rsquare.Value),'Units','normalized','Color',app.grey,'FontSize',14);
            
            hold(app.ADCMapFig,'off');
            hold(app.M0MapFig,'off');
            hold(app.R2MapFig,'off');
            drawnow;
            
        end % PlotMaps
        
        
        % Plots the fitresults in the 2 fit-windows
        function PlotFits(app)
            
          if app.validReco_flag  
              
            cla(app.ADClinearPlot);
            cla(app.ADClogPlot);
            hold(app.ADClinearPlot,'on');
            hold(app.ADClogPlot,'on');
            
            % Colormap for datapoints
            cmap = [hex2rgb(app.red) ; hex2rgb(app.blue)];
            tecolorflag = cmap(app.nr_selection + 1,:);
            tecolorflag = tecolorflag(1:app.nr,:);
            
            xcor = app.XEditField.Value;
            ycor = app.YEditField.Value;
            
            adc = app.adcmap(app.ADCMapSlicesEditField.Value,xcor,ycor);
            m0 = app.m0map(app.ADCMapSlicesEditField.Value,xcor,ycor);
            datapoints = squeeze(app.images(:,app.ADCMapSlicesEditField.Value,xcor,ycor));
            for i = 1:app.nr
                b(i) = app.binfo(1+(i-1)*app.ns).bvalue; %#ok<AGROW>
            end
            
            if (adc>0) && (m0>0)
                
                % fit
                a = 0 : 1.1*max(b(:));
                s = m0*exp(-a.*adc/1000);
                
                % plot linear
                scatter(app.ADClinearPlot,b,datapoints,[],tecolorflag,'filled');
                plot(app.ADClinearPlot,a,s);
                xlim(app.ADClinearPlot,[-0.5, 1.1*max(b)]);
                ylim(app.ADClinearPlot,[-0.5, 1.1*max(s)]);
               
                % plot log
                scatter(app.ADClogPlot,b,log(datapoints),[],tecolorflag,'filled');
                plot(app.ADClogPlot,a,log(s));
                xlim(app.ADClogPlot,[-0.5, 1.1*max(b)]);
                ylim(app.ADClogPlot,[0.9*min(log(s)), 1.01*max(log(s))]);
                
                % plot data point (for debugging)
                % hold(app.ADCMapFig,'on');
                % scatter(app.ADCMapFig,xcor,ycor,'filled','s','y');
                % hold(app.ADCMapFig,'off');
            
                % show ADC value in app
                app.ADCViewField.Value = adc;
                
            end
            
            hold(app.ADClinearPlot,'off');
            hold(app.ADClogPlot,'off');
            
          end
           
        end % PlotFits


        %------------------------------------------------
        %
        %  Parameters and settings
        %
        %------------------------------------------------


        % Sets global parameters
        function SetParameters(app)
           
            % message
            app.TextMessage('Setting scan parameters ...');
            
            % sequence parameters
            app.dimx = size(app.data,2);
            app.dimy = size(app.data,3);
            app.fov = app.dcmInfo{1}.FieldOfViewDimensions(1);
           
            % image aspect ratio
            app.AspectRatioViewField.Value = (app.dcmInfo{1}.FieldOfViewDimensions(2)/app.dcmInfo{1}.FieldOfViewDimensions(1));
            app.aspectRatio = (app.dcmInfo{1}.FieldOfViewDimensions(2)/app.dcmInfo{1}.FieldOfViewDimensions(1))*(app.dimx/app.dimy);
            
            app.sl = app.dcmInfo{1}.FieldOfViewDimensions(3);
            app.tr = app.dcmInfo{1}.RepetitionTime;
            app.te = app.dcmInfo{1}.EchoTime;
            app.ns = length(unique([app.binfo.positions]','rows'));
            app.nr = round(size(app.data,1)/app.ns);
            
            % show parameters & set limits
            app.TRViewField.Value = app.tr;
            app.TEViewField.Value = app.te;
            app.NRImagesViewField.Value = app.nr;
            app.dimxViewField.Value = app.dimx;
            app.dimyViewField.Value = app.dimy;
            app.SlicesViewField.Value = app.ns;
            app.SlicesEditField.Value = 1;
            app.SlicesEditField.Limits = [1, app.ns];
            app.SlicesSlider.Value = 1;
            app.SlicesSlider.Limits = [1, app.ns];
            app.ImageEditField.Value = 1;
            app.ImageEditField.Limits = [1, app.nr];
            app.ImageSlider.Value = 1;
            app.ImageSlider.Limits = [1, app.nr];
            app.ADCMapSlicesEditField.Limits = [1, app.ns];
            app.ADCMapSlicesSlider.Limits = [1, app.ns];
            app.ADCMapSlicesEditField2.Limits = [1, app.ns];
            app.ADCMapSlicesSlider2.Limits = [1, app.ns];
            app.SLTViewField.Value = app.sl;
            app.FOVViewField.Value = app.fov;
            
            app.adccmap = gray; 
            app.m0cmap = gray; 
            app.r2cmap = gray; 
            app.FirstSliceEditField.Value = 1;
            app.FirstSliceEditField.Limits = [1 app.ns];
            app.LastSliceEditField.Limits = [1 app.ns];
            app.LastSliceEditField.Value = app.ns;
            app.abortfit = false;
            
            % other parameters
            app.scalefactor = 1;
            app.ScaleSlider.Value = app.scalefactor;
           
            % define empty m0 and ADCmap
            app.m0map = zeros(app.ns,app.dimx,app.dimy);
            app.adcmap = zeros(app.ns,app.dimx,app.dimy);
            app.r2map = zeros(app.ns,app.dimx,app.dimy);
            
            % images
            app.images = [];
            app.images = reshape(app.data,app.ns,app.nr,app.dimx,app.dimy);
            app.images = permute(app.images,[2 1 3 4]);
            
            % normalize to convenient range
            app.images = round(16384*app.images/max(app.images(:)));
            app.validReco_flag = true;
            
            % define mask initially with ones
            app.mask = ones(app.ns,app.dimx,app.dimy);
            
            % reset treshold values
            app.threshold = zeros(1,app.ns);
            
            % initial b-value and grad direction
            indx = app.SlicesEditField.Value + app.ns*(app.ImageEditField.Value - 1);
            app.BvalueViewField.Value = num2str(app.binfo(indx).bvalue);
            app.GradViewField.Value = num2str(app.binfo(indx).grads');
            app.SwitchOnAll;
            
        end % SetParameters
    
        
        % Make b-value selection array
        function SelectData(app)
            
            app.nr_selection = [...
                app.CheckBox_1.Value  app.CheckBox_2.Value  app.CheckBox_3.Value  app.CheckBox_4.Value ...
                app.CheckBox_5.Value  app.CheckBox_6.Value  app.CheckBox_7.Value  app.CheckBox_8.Value ...
                app.CheckBox_9.Value  app.CheckBox_10.Value app.CheckBox_11.Value app.CheckBox_12.Value ...
                app.CheckBox_13.Value app.CheckBox_14.Value app.CheckBox_15.Value app.CheckBox_16.Value ...
                app.CheckBox_17.Value app.CheckBox_18.Value app.CheckBox_19.Value app.CheckBox_20.Value ...
                app.CheckBox_21.Value app.CheckBox_22.Value app.CheckBox_23.Value app.CheckBox_24.Value ...
                app.CheckBox_25.Value app.CheckBox_26.Value app.CheckBox_27.Value app.CheckBox_28.Value ...
                app.CheckBox_29.Value app.CheckBox_30.Value app.CheckBox_31.Value app.CheckBox_32.Value ...
                app.CheckBox_33.Value app.CheckBox_34.Value app.CheckBox_35.Value app.CheckBox_36.Value ...
                app.CheckBox_37.Value app.CheckBox_38.Value app.CheckBox_39.Value app.CheckBox_40.Value ...
                app.CheckBox_41.Value app.CheckBox_42.Value app.CheckBox_43.Value app.CheckBox_44.Value ...
                app.CheckBox_45.Value app.CheckBox_46.Value app.CheckBox_47.Value app.CheckBox_48.Value ...
                app.CheckBox_49.Value app.CheckBox_50.Value app.CheckBox_51.Value app.CheckBox_52.Value ...
                app.CheckBox_53.Value app.CheckBox_54.Value app.CheckBox_55.Value app.CheckBox_56.Value ...
                app.CheckBox_57.Value app.CheckBox_58.Value app.CheckBox_59.Value app.CheckBox_60.Value ...
                app.CheckBox_61.Value app.CheckBox_62.Value app.CheckBox_63.Value app.CheckBox_64.Value ...
                ];
            app.nr_selection = [app.nr_selection ones(1,1024)];
            app.nr_selection = app.nr_selection(1:app.nr);
            
        end
        
        % Set default values for which b-values to include with fitting
        function SwitchOnAll(app)
            
                app.CheckBox_1.Value = 1; app.CheckBox_2.Value = 1; app.CheckBox_3.Value = 1; app.CheckBox_4.Value = 1; ...
                app.CheckBox_5.Value = 1; app.CheckBox_6.Value = 1; app.CheckBox_7.Value = 1; app.CheckBox_8.Value = 1; ...
                app.CheckBox_9.Value = 1; app.CheckBox_10.Value = 1; app.CheckBox_11.Value = 1; app.CheckBox_12.Value = 1; ...
                app.CheckBox_13.Value = 1; app.CheckBox_14.Value = 1; app.CheckBox_15.Value = 1; app.CheckBox_16.Value = 1; ...
                app.CheckBox_17.Value = 1; app.CheckBox_18.Value = 1; app.CheckBox_19.Value = 1; app.CheckBox_20.Value = 1; ...
                app.CheckBox_21.Value = 1; app.CheckBox_22.Value = 1; app.CheckBox_23.Value = 1; app.CheckBox_24.Value = 1; ...
                app.CheckBox_25.Value = 1; app.CheckBox_26.Value = 1; app.CheckBox_27.Value = 1; app.CheckBox_28.Value = 1; ...
                app.CheckBox_29.Value = 1; app.CheckBox_30.Value = 1; app.CheckBox_31.Value = 1; app.CheckBox_32.Value = 1; ...
                app.CheckBox_33.Value = 1; app.CheckBox_34.Value = 1; app.CheckBox_35.Value = 1; app.CheckBox_36.Value = 1; ...
                app.CheckBox_37.Value = 1; app.CheckBox_38.Value = 1; app.CheckBox_39.Value = 1; app.CheckBox_40.Value = 1; ...
                app.CheckBox_41.Value = 1; app.CheckBox_42.Value = 1; app.CheckBox_43.Value = 1; app.CheckBox_44.Value = 1; ...
                app.CheckBox_45.Value = 1; app.CheckBox_46.Value = 1; app.CheckBox_47.Value = 1; app.CheckBox_48.Value = 1; ...
                app.CheckBox_49.Value = 1; app.CheckBox_50.Value = 1; app.CheckBox_51.Value = 1; app.CheckBox_52.Value = 1; ...
                app.CheckBox_53.Value = 1; app.CheckBox_54.Value = 1; app.CheckBox_55.Value = 1; app.CheckBox_56.Value = 1; ...
                app.CheckBox_57.Value = 1; app.CheckBox_58.Value = 1; app.CheckBox_59.Value = 1; app.CheckBox_60.Value = 1; ...
                app.CheckBox_61.Value = 1; app.CheckBox_62.Value = 1; app.CheckBox_63.Value = 1; app.CheckBox_64.Value = 1;
            
        end


    end % methods


    % ----------------------------------------------
    %
    % Public functions, available outside the app
    %
    % ----------------------------------------------


    methods (Access = public)


        % Text messages in the app
        function TextMessage(app, message)
            
            app.MessageBox.Value = [message ; app.MessageBox.Value];
            drawnow;
            
        end % TextMessage
        
        
        % Status icon
        function SetStatus(app,status)
            
            switch status
                
                case 0
                    app.StatusImage.ImageSource = 'statusokay.gif';
                    app.statusLabel.Text = 'OK';
                    app.statusLabel.FontColor = app.green;
                    
                case 1
                    app.StatusImage.ImageSource = 'statuswarning.gif';
                    app.statusLabel.Text = 'warning';
                    app.statusLabel.FontColor = app.orange;
                    
                case 2
                    app.StatusImage.ImageSource = 'statuserror.gif';
                    app.statusLabel.Text = 'error';
                    app.statusLabel.FontColor = app.brightred;
                    
                case 3
                    app.StatusImage.ImageSource = 'statuswait.gif';
                    app.statusLabel.Text = 'busy';
                    app.statusLabel.FontColor = 'black';
                    
            end
            
            drawnow;
            
        end


    end % methods (Access = public)


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)
            
            % main window noname
            app.ADCmapUIFigure.Name = '';
            
            % start the parallel pool if it is not running already
            p = gcp('nocreate');
            if isempty(p)
                try
                    parpool(4);
                catch
                    parpool;
                end
            end
            
            % empty file import and export paths
            app.dicomImportPath = '';
            app.dicomExportPath = '';
            app.gifExportPath = '';
            app.gradPath = '';
                       
            % add subfolders to search path
            if (~isdeployed)
                app.olddir = pwd;
                info = dir(which('ADCmapp.mlapp'));
                app.appdir = info.folder;
                cd(app.appdir);
                addpath(genpath(pwd));
            end
            
            % disable unnecessary warnings
            clc;
            warning off;
            
            % Initialize user interface
            app.InitializeUI;
        
        end

        % Button pushed function: LoadMRDfileButton
        function LoadDicomFileButtonPushed(app, event)
            
            % Indicate busy
            app.SetLights([0,0,0,0,0,0]);
            app.SetButtons([0,0,0,0,0,0,0]);
            app.SetFitButtons(0);
            app.SetImageSelectionButtons(0);
            app.SetSegmentationButtons(0);
            app.SetMapButtons(0);
            app.SetStatus(3);
            
            % valid flags
            validfile = false;
            app.validData_flag = false;
            app.validReco_flag = false;
            app.validFit_flag = false;
            
            % Ask for dicom import directory
            try
                app.ADCmapUIFigure.Visible = 'off';
                app.dicomImportPath = uigetdir(app.dicomImportPath,'Dicom import path');
                app.ADCmapUIFigure.Visible = 'on';
                drawnow;
            catch ME
                app.TextMessage(ME.message);
            end
            
            % Directory tag
            app.tag = strsplit(app.dicomImportPath,filesep);
            app.tag = app.tag(end);
            app.tag = app.tag{1};
            
            % List of dicom file names
            flist = dir(fullfile(app.dicomImportPath,'*.dcm'));
            
            if ~isempty(flist)

                try

                    % read dicom images and info
                    app.TextMessage('Reading Dicom images and tags ...');
                    app.dcmInfo = {};
                    app.data = [];
                    for i = 1:length(flist)
                        app.dcmInfo{i} = dicominfo([app.dicomImportPath,filesep,flist(i).name]);
                        app.data(i,:,:) = dicomread([app.dicomImportPath,filesep,flist(i).name]);
                    end
                    app.DicomDirViewField.Value = app.dicomImportPath;

                    % permute x and y dimensions
                    if ndims(app.data) == 3
                        app.data = permute(flip(app.data,2),[1 3 2]);
                    end

                    % make a list of the b-values and gradient directions, if exist
                    app.bValueInfo = {};
                    if isfield(app.dcmInfo{1},'DiffusionBValue') && isfield(app.dcmInfo{1},'DiffusionGradientOrientation')
                        for i = 1:length(flist)
                            app.bValueInfo{i}.indx = i;
                            app.bValueInfo{i}.bvalue = app.dcmInfo{i}.DiffusionBValue;
                            app.bValueInfo{i}.grads = app.dcmInfo{i}.DiffusionGradientOrientation;
                            app.bValueInfo{i}.positions = app.dcmInfo{i}.ImagePositionPatient;
                        end

                        % check whether there are at least 2 b-values in this acquisition
                        app.binfo = cell2mat(app.bValueInfo);
                        uniquebvalues = unique([app.binfo.bvalue]);
                        if length(uniquebvalues) > 1
                            app.TextMessage(strcat('B-values = ',{' '},num2str(uniquebvalues)));
                            app.TextMessage(strcat('Gradient direction = ',{' '},num2str(unique([app.binfo.grads]','rows'))));
                            validfile = true;
                        end
                    else
                        app.TextMessage('ERROR: Data does not seem to contain diffusion-weighted images ...');
                    end

                catch ME

                    app.TextMessage('ERROR: During data loading ...');
                    app.TextMessage(ME.message);

                end
                
                
            end
            
            if validfile
                
                app.SetParameters;
                
                % reset buttons
                app.SetLights([1,0,0,0,0,0]);
                app.SetButtons([1,1,1,0,0,0,1]);
                app.validData_flag = true;
                app.SetImageSelectionButtons(1);
                app.SetStatus(0);
                                
                % Show the image
                app.PlotRawImages;
                app.PlotMaps;
                
            else
                
                % not a valid MRD file with navigator
                f = app.ADCmapUIFigure;
                uialert(f,'Please select a valid DCM direcotry','Error');
                app.dicomImportPath = '';
                app.validData_flag = false;
                app.SetLights([0,0,0,0,0,0]);
                app.SetButtons([1,0,0,0,0,0,1]);
                app.SetStatus(2);
                
            end
            
        end

        % Value changed function: TEViewField
        function TEViewFieldValueChanged(app, event)
            
            app.te = app.TEViewField.Value;
            app.parameters.te = app.TEViewField.Value;
            app.esp = app.ESPEditField.Value;
            app.tes = app.te + app.esp*linspace(0,app.ne-1,app.ne)'; % echo times
            app.TextMessage(strcat('Echo times = ',{' '},num2str(app.tes')));
            
        end

        % Value changed function: ImageEditField, SlicesEditField
        function SlicesEditFieldValueChanged(app, event)
            
            % Adjust the slider
            app.SlicesSlider.Value = app.SlicesEditField.Value;
            app.ImageSlider.Value = app.ImageEditField.Value;
            
            indx = app.SlicesEditField.Value + app.ns*(app.ImageEditField.Value - 1);
            app.BvalueViewField.Value = num2str(app.binfo(indx).bvalue);
            app.GradViewField.Value = num2str(app.binfo(indx).grads');
        
            % Plot the raw image 
            PlotRawImages(app);
            
        end

        % Value changed function: SlicesSlider
        function SlicesSliderValueChanged(app, event)
            
            % Adjust the slices edit field value
            app.SlicesEditField.Value = round(app.SlicesSlider.Value);
            
            % Continue with the changed slices edit field callback
            SlicesEditFieldValueChanged(app, event)
            
        end

        % Value changed function: ImageSlider
        function ImageSliderValueChanged(app, event)
            
            % Adjust the echoes edit field value
            app.ImageEditField.Value = round(app.ImageSlider.Value);
            
            % Continue with the changed slices / echoes edit field callback
            SlicesEditFieldValueChanged(app, event)
            
        end

        % Value changed function: ScaleSlider
        function ScaleSliderValueChanged(app, event)
            
                % change the image intensity scale
                app.scalefactor = app.ScaleSlider.Value;
            
                % replot the images
                PlotRawImages(app)
            
        end

        % Button pushed function: RegistrationButton
        function RegistrationButtonPushed(app, event)
            
            success = false;
            
            if app.validReco_flag
                
                % Indicate busy
                app.SetLights([1,0,0,0,0,0]);
                app.SetButtons([0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(0);
                app.SetStatus(3);
                app.TextMessage('Registering images ....');
                
                % Initialize progress gauge
                app.RegProgressGauge.Value = 0;
                
                % Register all slices to 1st echo time image
                try
                    app.images = register_images(app,app.images);
                    success = true;
                catch ME
                    app.TextMessage(ME.message);
                    success = false;
                end
                
            end
            
            if success
                
                % reset m0 and ADCmap
                app.m0map = zeros(app.ns,app.dimx,app.dimy);
                app.adcmap = zeros(app.ns,app.dimx,app.dimy);
                app.r2map = zeros(app.ns,app.dimx,app.dimy);
                app.mask = ones(app.ns,app.dimx,app.dimy);
                
                % replot the images
                PlotRawImages(app);
                
                % Reset buttons
                app.SetLights([1,1,0,0,0,0]);
                app.SetButtons([1,1,1,0,0,0,1]);
                app.SetImageSelectionButtons(1);
                app.SetStatus(0);
                app.validFit_flag = false;
                
            else
                
                app.TextMessage('ERROR: Something went wrong during registration ...');
                app.SetStatus(2);
                app.validFit_flag = false;
                
            end
            
        end

        % Button pushed function: ImportGradTableButton
        function ImportGradTableButtonPushed(app, event)
            
            % Valid file
            valid_gradfile = false;
            
            % Ask for file
            try
                app.ADCmapUIFigure.Visible = 'off';
                [gradfile,app.gradPath] = uigetfile([app.gradPath,filesep,'*.*']);
                app.ADCmapUIFigure.Visible = 'on';
                drawnow;
            catch ME
                app.TextMessage(ME.message);
            end

            
            % Load the grad table
            try
                btable = dlmread([app.gradPath,gradfile]); %#ok<DLMRD>
                vfile = true;
            catch ME
                app.TextMessage(ME.message);
                app.TextMessage('ERROR reading b-value & gradient table file ...');
                vfile = false;
            end
            
            % Interprete the grad table and b-values
            try
                
                if vfile

                    if (isequal(size(btable),[app.nr 4]))

                        bvalues = btable(:,1);
                        gvalues = btable(:,2:4);

                        if (min(bvalues(:)) >= 0) && (min(gvalues(:) >= -1) && (max(gvalues(:)) <= 1))

                            cnt = 1;
                            for j = 1:app.nr
                                for i = 1:app.ns
                                    app.binfo(cnt).bvalue = bvalues(j);
                                    app.binfo(cnt).grads = gvalues(j,:)';
                                    cnt = cnt + 1;
                                end
                            end

                            valid_gradfile = true;

                        else
                            app.TextMessage('ERROR: B-values should be > 0 & gradient directions [-1 ... 1] ...');
                        end

                    else
                        app.TextMessage('ERROR: Size of b-value & gradient table is not consistent with size of data ...');
                    end

                end

            catch ME

                app.TextMessage(ME.message);
                app.TextMessage('ERROR: during interpretation of gradient file ...');
                valid_gradfile = false;

            end
            
            % Successful or not
            if valid_gradfile
                
                app.TextMessage('Import of b-value & gradient table successful ...');
                app.SetStatus(0);
                
                app.TextMessage(strcat('B-values = ',{' '},num2str(unique([app.binfo.bvalue]))));
                app.TextMessage(strcat('Gradient direction = ',{' '},num2str(unique([app.binfo.grads]','rows'))));
                
            else
                
                app.TextMessage('ERROR: Something went wrong with import of b-value & gradient table ...');
                app.SetStatus(2);
                
            end
            
        end

        % Button pushed function: ThresholdButton
        function ThresholdButtonPushed(app, event)
            
            if app.validReco_flag
                
                % Indicate busy
                app.SetLights([1,2,0,0,0,0]);
                app.SetButtons([0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(0);
                app.SetStatus(3);
                app.TextMessage('Thresholding images ....');

                try

                    % Determine automatic threshold using the Otsu method for each slice
                    for i=1:app.ns
                        app.threshold(i) = graythresh(mat2gray(app.images(1,i,:,:))) * max(squeeze(app.images(1,i,:)));
                    end

                    % Set the automatic mask for all slices
                    app.mask = ones(app.ns,app.dimx,app.dimy);
                    for i=1:app.ns
                        app.mask(i,:,:) = apply_threshold_adc(squeeze(app.images(1,i,:,:)),app.threshold(i));
                    end

                    % Display the threshold value for the current slice
                    app.ThresholdEditField.Value = app.threshold(app.SlicesEditField.Value);

                    % Display the mask in the image
                    PlotRawImages(app);

                catch ME

                    app.TextMessage(ME.message);

                end
                
                % Indicate busy
                app.SetLights([1,2,1,0,0,0]);
                app.SetButtons([1,1,1,1,0,0,1]);
                app.SetFitButtons(1);
                app.SetImageSelectionButtons(1);
                app.SetSegmentationButtons(1);
                app.SetMapButtons(0);
                app.SetStatus(0);
                
            end
            
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            
            % Get the current slice
            cslice = app.SlicesEditField.Value;
            
            % Change the value of the threshold for this slice and update the slider
            app.threshold(cslice) = app.ThresholdEditField.Value;
            app.ThresholdSlider.Value = app.ThresholdEditField.Value;

            % Apply the new threshold
            try
                app.mask(cslice,:,:) = apply_threshold_adc(squeeze(app.images(1,cslice,:,:)),app.threshold(cslice));
            catch ME
                app.TextMessage(ME.message);
            end

            % Display the result;
            PlotRawImages(app)

        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            
            % Threshold slider changed
            % Adjust Threshold and call threshold change button
            app.ThresholdEditField.Value = app.ThresholdSlider.Value;
            ThresholdEditFieldValueChanged(app, event);
            
        end

        % Button pushed function: ErodeButton
        function ErodeButtonPushed(app, event)
            
            % Morphological erosion on current slice
            try
                
                app.mask(app.SlicesEditField.Value,:,:) = 1-imerode(1-squeeze(app.mask(app.SlicesEditField.Value,:,:)),[0 1 0; 1 1 1; 0 1 0]);

                % Display the result;
                PlotRawImages(app);

            catch ME
                
                app.TextMessage(ME.message);
                
            end
            
        end

        % Button pushed function: DilateButton
        function DilateButtonPushed(app, event)
        
            % Morphological dilation on current slice
            
            try
                
                app.mask(app.SlicesEditField.Value,:,:) = 1-imdilate(1-squeeze(app.mask(app.SlicesEditField.Value,:,:)),[0 1 0; 1 1 1; 0 1 0]);

                % Display the result;
                PlotRawImages(app);

            catch ME

                app.TextMessage(ME.message);

            end
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            
            try

                % Morphological dilation on current slice
                app.mask(app.SlicesEditField.Value,:,:) = 1-imclose(1-squeeze(app.mask(app.SlicesEditField.Value,:,:)),[0 1 0; 1 1 1; 0 1 0]);

                % Display the result;
                PlotRawImages(app);

            catch ME

                app.TextMessage(ME.message);

            end
            
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            
            try

                % Morphological dilation on current slice
                app.mask(app.SlicesEditField.Value,:,:) = 1-imopen(1-squeeze(app.mask(app.SlicesEditField.Value,:,:)),[0 1 0; 1 1 1; 0 1 0]);

                % Display the result;
                PlotRawImages(app);

            catch ME

                app.TextMessage(ME.message);

            end
            
        end

        % Button pushed function: ADCFitButton
        function ADCFitButtonPushed(app, event)
            
            ready = false;
            
            if app.validReco_flag
                
                app.SetLights([1,2,1,0,0,0]);
                app.SetButtons([0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(1);
                app.SetStatus(3);
                app.TextMessage('Fitting ADC maps ....');

                app.validFit_flag = false;
                app.abortfit = false;

                % Organize the b-values and gradient directions
                app.SelectData;

                if nnz(app.nr_selection) > 1

                    try

                        % for selected slices
                        for i=app.FirstSliceEditField.Value:app.LastSliceEditField.Value

                            % Stop if abort button is pressed
                            if app.abortfit == true
                                break;
                            end

                            % Do the fit for all coordinates, parallel accelerated
                            [app.m0map(i,:,:),app.adcmap(i,:,:),app.r2map(i,:,:)] = dotheADCfit_slice(squeeze(app.images(:,i,:,:)), squeeze(app.mask(i,:,:)), app.nr, app.ns, app.binfo, app.nr_selection, app.ThresholdR.Value, app.Rsquare.Value);

                            % Update the progress bar
                            app.FitProgressGauge.Value = round(100 * (i + 1 - app.FirstSliceEditField.Value) / (app.LastSliceEditField.Value - app.FirstSliceEditField.Value + 1) );
                            drawnow;

                            % Plot Raw images current slice
                            PlotRawImages(app);

                            % Plot ADC map thus far with auto scale
                            % Auto-scale = 2 x mean of the ADC values in the image
                            scale = 2*mean(nonzeros(squeeze(app.adcmap(app.ADCMapSlicesEditField.Value,:))));
                            scale(isnan(scale)) = 3;
                            scale(scale<0.1) = 0.1;
                            app.ADCScaleEditField.Value = scale;
                            PlotMaps(app);

                        end

                        ready = true;

                    catch ME

                        app.TextMessage(ME.message);
                        app.TextMessage('ERROR: Something went wrong during ADC fitting ...');
                        ready = false;

                    end

                else

                    app.TextMessage('ERROR: ADC fitting needs at least 2 datapoints ...');

                end

            end

            if ready
                
                % Reset buttons
                app.SetLights([1,2,1,1,0,0]);
                app.SetButtons([1,1,1,1,1,1,1]);
                app.SetFitButtons(1);
                app.SetImageSelectionButtons(1);
                app.SetSegmentationButtons(1);
                app.SetMapButtons(1);
                app.SetStatus(0);
                app.validFit_flag = true;
                
            else
                
                % Reset buttons
                app.SetLights([1,2,1,0,0,0]);
                app.SetButtons([1,1,1,1,1,1,1]);
                app.SetFitButtons(1);
                app.SetImageSelectionButtons(1);
                app.SetSegmentationButtons(1);
                app.SetMapButtons(1);
                app.SetStatus(2);
                app.validFit_flag = false;
                
            end
         
        end

        % Button pushed function: AbortFitButton
        function AbortFitButtonPushed(app, event)
            
            app.abortfit = true; 
        
        end

        % Value changed function: FirstSliceEditField, LastSliceEditField
        function FirstSliceEditFieldValueChanged(app, event)
            
            if app.FirstSliceEditField.Value > app.LastSliceEditField.Value
                app.LastSliceEditField.Value = app.FirstSliceEditField.Value;
            end
            
        end

        % Value changed function: ADCMapSlicesEditField
        function ADCMapSlicesEditFieldValueChanged(app, event)
            
            % Change the slider value according to the edit number
            app.ADCMapSlicesSlider.Value = round(app.ADCMapSlicesEditField.Value);
            app.ADCMapSlicesEditField2.Value = app.ADCMapSlicesEditField.Value;
            app.ADCMapSlicesSlider2.Value = app.ADCMapSlicesSlider.Value;
            
            % Plot the ADC-map for the new slice
            PlotMaps(app);
            
        end

        % Value changed function: ADCMapSlicesEditField2
        function ADCMapSlicesEditField2ValueChanged(app, event)
            
            % Change the slider value according to the edit number
            app.ADCMapSlicesSlider2.Value = round(app.ADCMapSlicesEditField2.Value);
            app.ADCMapSlicesEditField.Value = app.ADCMapSlicesEditField2.Value;
            app.ADCMapSlicesSlider.Value = app.ADCMapSlicesSlider2.Value;
            
            % Plot the ADC-map for the new slice
            PlotMaps(app);
            
        end

        % Value changed function: ADCMapSlicesSlider
        function ADCMapSlicesSliderValueChanged(app, event)
            
            % Change the Slice nr value according to the slider
            app.ADCMapSlicesEditField.Value = round(app.ADCMapSlicesSlider.Value);
            app.ADCMapSlicesEditField2.Value = round(app.ADCMapSlicesSlider.Value);
            app.ADCMapSlicesSlider2.Value = app.ADCMapSlicesSlider.Value;
            
            % Plot the ADC-map for the new slice
            PlotMaps(app);
            
        end

        % Value changed function: ADCMapSlicesSlider2
        function ADCMapSlicesSlider2ValueChanged(app, event)
            
            % Change the Slice nr value according to the slider
            app.ADCMapSlicesEditField2.Value = round(app.ADCMapSlicesSlider2.Value);
            app.ADCMapSlicesEditField.Value = round(app.ADCMapSlicesSlider2.Value);
            app.ADCMapSlicesSlider.Value = app.ADCMapSlicesSlider2.Value;
            
            % Plot the ADC-map for the new slice
            PlotMaps(app);
            
        end

        % Value changed function: ADCScaleEditField
        function ADCScaleEditFieldValueChanged(app, event)
            
            % Plot the ADC map with the new scale
            PlotMaps(app);
            
        end

        % Button pushed function: ADCScaleAutoButton
        function ADCScaleAutoButtonPushed(app, event)
            
            % Estimate a suitable scale for the ADC map
            % Auto-scale = 2 x mean of the ADC values in the image
            scale = round(2*mean(nonzeros(squeeze(app.adcmap(app.ADCMapSlicesEditField.Value,:))))); 
            scale(isnan(scale)) = 3;
            scale(scale<0.1) = 0.1;
            app.ADCScaleEditField.Value = scale;
            
            % Plot the map
            PlotMaps(app);
            
        end

        % Selection changed function: ColorMapButtonGroup
        function ColorMapButtonGroupSelectionChanged2(app, event)
            
            if app.grayButton.Value == 1 
                app.adccmap = gray; 
            end
            
            if app.autumnButton.Value == 1
                app.adccmap = autumn; 
            end
            
            if app.winterButton.Value == 1
                app.adccmap = winter; 
            end
            
            if app.parulaButton.Value == 1
                app.adccmap = parula; 
            end
            
            if app.hotButton.Value == 1
                app.adccmap = hot; 
            end
            
            % Background should always be black
            app.adccmap(1,:) = [0 0 0];
            
            % Plot the ADC map with the new colormap
            PlotMaps(app);
            
        end

        % Selection changed function: ColorMapButtonGroup2
        function ColorMapButtonGroup2SelectionChanged(app, event)
            
            if app.grayButton2.Value == 1 
                app.m0cmap = gray; 
            end
            
            if app.autumnButton2.Value == 1
                app.m0cmap = autumn; 
            end
            
            if app.winterButton2.Value == 1
                app.m0cmap = winter; 
            end
            
            if app.parulaButton2.Value == 1
                app.m0cmap = parula; 
            end
            
            if app.hotButton2.Value == 1
                app.m0cmap = hot; 
            end
            
            % Background should always be black
            app.m0cmap(1,:) = [0 0 0];
            
            % Plot the M0 map with the new colormap
            PlotMaps(app);
            
        end

        % Selection changed function: ColorMapButtonGroup3
        function ColorMapButtonGroup3SelectionChanged(app, event)
              
            if app.grayButton3.Value == 1 
                app.r2cmap = gray; 
            end
            
            if app.autumnButton3.Value == 1
                app.r2cmap = autumn; 
            end
            
            if app.winterButton3.Value == 1
                app.r2cmap = winter; 
            end
            
            if app.parulaButton3.Value == 1
                app.r2cmap = parula; 
            end
            
            if app.hotButton3.Value == 1
                app.r2cmap = hot; 
            end
            
            % Background should always be black
            app.r2cmap(1,:) = [0 0 0];
            
            % Plot the R2 map with the new colormap
            PlotMaps(app);
            
            
        end

        % Image clicked function: ADCImage
        function ADCImageClicked(app, event)
            
                % Get the Position of the GUI and the position of the mouse click
                set(0,'units','pixels');
                guiposition = get(app.ADCmapUIFigure,'Position');
                cursorposition = get(0,'PointerLocation');
                               
                % Position of the cursor click in the image window
                imagepos = [862,412]; % position of bottom-left corner of image in the app (862,412)
                xpos = cursorposition(1)-guiposition(1)-imagepos(1);
                ypos = cursorposition(2)-guiposition(2)-imagepos(2);
                
                % Correct for non-square image
                imsize = 313;
                if app.AspectRatioViewField.Value <= 1
                    xcor = (xpos/imsize) * app.dimx;
                    ycor = (ypos/imsize) * app.dimy * (1/app.AspectRatioViewField.Value) - (0.5 * ((1/app.AspectRatioViewField.Value)-1) * app.dimy) + 1;
                else
                    xcor = (xpos/imsize) * app.dimx * app.AspectRatioViewField.Value - (0.5 * (app.AspectRatioViewField.Value-1) * app.dimx) + 1;     % untested
                    ycor = (ypos/imsize) * app.dimy;
                end
                
                % Round to nearest integer and keep within boundaries
                xcor = round(xcor);
                ycor = round(ycor);
                xcor(xcor<1) = 1;
                ycor(ycor<1) = 1;
                xcor(xcor>app.dimx) = app.dimx;
                ycor(ycor>app.dimy) = app.dimy;
                
                app.XEditField.Value = xcor;
                app.YEditField.Value = ycor;
                
                % Plot the results of the fits in the chosen pixel
                app.PlotFits;
                
               
        end

        % Button pushed function: ExportDicomButton
        function ExportDicomButtonPushed(app, event)
            
            if app.validFit_flag

                % Indicate busy
                app.SetLights([1,2,1,1,0,2]);
                app.SetButtons([0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(0);
                app.SetStatus(3);

                try

                    % Base / Data / 343 / DICOM / 8455 /
                    dcmSpath{1} = app.dicomImportPath;
                    try
                        k = strfind(dcmSpath{1},"Data");
                        fs = strfind(dcmSpath{1}(k:end),filesep);
                        dcmSpath{1}= dcmSpath{1}(1:k+fs(3)-1);
                        dcmSpath{1} = insertAfter(dcmSpath{1},k+fs(2)-1,strcat("DICOM",filesep));
                        dcmIndx{1} = 2;
                    catch
                        dcmSpath{1} = app.mrdImportPath;
                    end

                    % Base / data / 343 / DICOM / 8455 / 1 /
                    dcmSpath{2} = strcat(dcmSpath{1},'1',filesep);
                    dcmIndx{2} = 3;

                    % Base / data / 343 / DICOM / 8455 / 2 /
                    dcmSpath{3} = strcat(dcmSpath{1},'2',filesep);
                    dcmIndx{3} = 3;

                    % Base / data / 343 / DICOM / 8455 / 3 /
                    dcmSpath{4} = strcat(dcmSpath{1},'3',filesep);
                    dcmIndx{4} = 3;

                    % Check if one of the directories contains dicom files
                    dcmExist = false;
                    for i=1:length(dcmSpath)
                        if ~isempty(dir(strcat(dcmSpath{i},"*.dcm")))
                            dcmPath = dcmSpath{i};
                            app.TextMessage(strcat("Found DICOM information in ",dcmPath));
                            filesepIdx = strfind(dcmPath, filesep);
                            app.dicomExportPath = dcmPath(1:filesepIdx(end-dcmIndx{i}));
                            dcmExist = true;
                            break;
                        end
                    end

                    % Export to DICOM
                    if dcmExist

                        % Auto
                        export_dicom_adc(app.dicomExportPath,app.dcmInfo,app.adcmap,app.m0map,app.r2map);

                    else

                        % Ask for dicom export directory
                        app.ADCmapUIFigure.Visible = 'off';
                        app.dicomExportPath = uigetdir(app.dicomExportPath,'Dicom export path');
                        app.ADCmapUIFigure.Visible = 'on';
                        drawnow;
                        if ~ischar(app.dicomExportPath)
                            ME = MException('ADCmapp:userCancel','User cancel ...');
                            throw(ME);
                        end
                        app.dicomExportPath = strcat(app.dicomExportPath,filesep,"DICOM",filesep);
                        export_dicom_adc(app.dicomExportPath,app.dcmInfo,app.adcmap,app.m0map,app.r2map);
                    end

                    % Success
                    app.SetLights([1,2,1,1,1,2]);
                    app.SetButtons([1,1,1,1,1,1,1]);
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(1);
                    app.SetStatus(0);

                catch ME

                    % Failure
                    app.TextMessage(ME.message);
                    app.TextMessage('ERROR: Something went wrong during Dicom export ...');
                    app.SetStatus(2);
                    app.SetLights([1,2,1,1,0,2]);
                    app.SetButtons([1,1,1,1,1,1,1]);
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(1);

                end

            end
        
        end

        % Button pushed function: ExportGifButton
        function ExportGifButtonPushed(app, event)
         
            if app.validFit_flag

                % Indicate busy
                app.SetLights([1,2,1,1,0,0]);
                app.SetButtons([0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(0);
                app.SetStatus(3);

                % Export to animated gif
                try

                    app.TextMessage('GIF export ...');

                    try
                        app.gifExportPath = replace(app.mrdImportPath, "MRD", "Data");
                        k = strfind(app.gifExportPath,"Data");
                        fs = strfind(app.gifExportPath(k:end),filesep);
                        app.gifExportPath = app.gifExportPath(1:k+fs(2)-1);
                        gifPathExist = true;
                    catch
                        gifPathExist = false;
                    end

                    if ~gifPathExist
                        app.ADCmapUIFigure.Visible = 'off';
                        app.gifExportPath = uigetdir(app.gifExportPath,'GIF export path');
                        app.ADCmapUIFigure.Visible = 'on';
                        drawnow;
                        if ~ischar(app.gifExportPath)
                            ME = MException('Retrospective:userCancel','User cancel ...');
                            throw(ME);
                        end
                    end

                    app.gifExportPath = strcat(app.gifExportPath,filesep,"GIF",filesep);
                    app.gifExportPath = strrep(app.gifExportPath , strcat(filesep,filesep), filesep);
                    app.TextMessage(strcat("Gif export directory = ",app.gifExportPath));

                    export_gif_adc(app.gifExportPath,app.adcmap,app.m0map,app.r2map,app.tag,app.ADCScaleEditField.Value,app.adccmap,app.m0cmap,app.r2cmap,app.aspectRatio,app.Rsquare.Value);

                    % Success
                    app.TextMessage('GIF export ready ...');
                    app.SetLights([1,2,1,1,2,1]);
                    app.SetButtons([1,1,1,1,1,1,1]);
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(1);
                    app.SetStatus(0);

                catch ME

                    % Failure
                    app.TextMessage(ME.message);
                    app.TextMessage('ERROR: Something went wrong during GIF export ...');
                    app.SetStatus(2);
                    app.SetLights([1,2,1,1,2,0]);
                    app.SetButtons([1,1,1,1,1,1,1]);
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(1);

                end

            end
        
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
         
            % Remove subfolders from search path
            if (~isdeployed)
                info = dir(which('ADCmapp.mlapp'));
                app.appdir = info.folder;
                rmpath(genpath(app.appdir));
                cd(app.olddir);
            end
            
            % Exit
            app.delete;
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ADCmapUIFigure = uifigure('Visible', 'off');
            app.ADCmapUIFigure.Name = 'ADCmapp';
            app.ADCmapUIFigure.Icon = fullfile(pathToMLAPP, 'ADCicon.png');
            app.ADCmappingLabel = uilabel(app.ADCmapUIFigure);
            app.ADCmappingLabel.Text = 'ADC mapping';
            app.Credits = uilabel(app.ADCmapUIFigure);
            app.Credits.Text = 'Gustav Strijkers, Amsterdam UMC, Version 2.0.1';
            app.TulpLogo = uiimage(app.ADCmapUIFigure);
            app.MessageBox = uitextarea(app.ADCmapUIFigure);
            app.MessageBox.Editable = 'off';
            app.MessageBox.Value = {'Messages ....'};
            app.BackgroundBox = uitextarea(app.ADCmapUIFigure);
            app.BackgroundBox.Editable = 'off';
            app.StatusImage = uiimage(app.ADCmapUIFigure);
            app.statusLabel = uilabel(app.ADCmapUIFigure);
            app.statusLabel.Text = 'status';
            app.MRSImage = uiimage(app.ADCmapUIFigure);
            app.TabGroup1 = uitabgroup(app.ADCmapUIFigure);
            app.DashboardTab = uitab(app.TabGroup1);
            app.DashboardTab.Title = 'Dashboard';
            app.LoadMRDfileButton = uibutton(app.DashboardTab, 'push');
            app.LoadMRDfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDicomFileButtonPushed, true);
            app.LoadMRDfileButton.Enable = 'off';
            app.LoadMRDfileButton.Text = 'Load Dicom';
            app.Lamp1 = uilamp(app.DashboardTab);
            app.ThresholdButton = uibutton(app.DashboardTab, 'push');
            app.ThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @ThresholdButtonPushed, true);
            app.ThresholdButton.Enable = 'off';
            app.ThresholdButton.Text = 'Threshold';
            app.Lamp3 = uilamp(app.DashboardTab);
            app.ADCFitButton = uibutton(app.DashboardTab, 'push');
            app.ADCFitButton.ButtonPushedFcn = createCallbackFcn(app, @ADCFitButtonPushed, true);
            app.ADCFitButton.Enable = 'off';
            app.ADCFitButton.Text = 'ADC Fit';
            app.Lamp4 = uilamp(app.DashboardTab);
            app.FitProgressGauge = uigauge(app.DashboardTab, 'linear');
            app.FitProgressGauge.MajorTicks = [0 25 50 75 100];
            app.Lamp5 = uilamp(app.DashboardTab);
            app.ExitButton = uibutton(app.DashboardTab, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Enable = 'off';
            app.ExitButton.Text = 'Exit';
            app.ExportDicomButton = uibutton(app.DashboardTab, 'push');
            app.ExportDicomButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDicomButtonPushed, true);
            app.ExportDicomButton.Enable = 'off';
            app.ExportDicomButton.Text = 'Export Dicom';
            app.FitProgressLabel = uilabel(app.DashboardTab);
            app.FitProgressLabel.Text = 'Progress';
            app.Lamp6 = uilamp(app.DashboardTab);
            app.ExportGifButton = uibutton(app.DashboardTab, 'push');
            app.ExportGifButton.ButtonPushedFcn = createCallbackFcn(app, @ExportGifButtonPushed, true);
            app.ExportGifButton.Enable = 'off';
            app.ExportGifButton.Text = 'Export Gif';
            app.AbortFitButton = uibutton(app.DashboardTab, 'push');
            app.AbortFitButton.ButtonPushedFcn = createCallbackFcn(app, @AbortFitButtonPushed, true);
            app.AbortFitButton.Enable = 'off';
            app.AbortFitButton.Text = 'Abort';
            app.RegistrationButton = uibutton(app.DashboardTab, 'push');
            app.RegistrationButton.ButtonPushedFcn = createCallbackFcn(app, @RegistrationButtonPushed, true);
            app.RegistrationButton.Enable = 'off';
            app.RegistrationButton.Text = 'Registration';
            app.Lamp2 = uilamp(app.DashboardTab);
            app.RegProgressGauge = uigauge(app.DashboardTab, 'linear');
            app.RegProgressGauge.MajorTicks = [0 25 50 75 100];
            app.RegProgressLabel = uilabel(app.DashboardTab);
            app.RegProgressLabel.Text = 'Progress';
            app.TabGroup3 = uitabgroup(app.ADCmapUIFigure);
            app.ADCmapTab = uitab(app.TabGroup3);
            app.ADCmapTab.Title = 'ADC-map';
            app.ADCMapFig = uiaxes(app.ADCmapTab);
            app.ADCMapFig.XTick = [];
            app.ADCMapFig.YTick = [];
            app.ADClogPlot = uiaxes(app.ADCmapTab);
            title(app.ADClogPlot, 'Log')
            xlabel(app.ADClogPlot, 'b')
            ylabel(app.ADClogPlot, 'log ( Signal )')
            app.ADClinearPlot = uiaxes(app.ADCmapTab);
            title(app.ADClinearPlot, 'Linear')
            xlabel(app.ADClinearPlot, 'b')
            ylabel(app.ADClinearPlot, 'Signal')
            app.ADCMapLabel = uilabel(app.ADCmapTab);
            app.ADCMapLabel.Text = 'ADC map';
            app.ImageSelectorPanel = uipanel(app.ADCmapTab);
            app.ImageSelectorPanel.Title = 'Image Selector';
            app.ADCMapSlicesSlider = uislider(app.ImageSelectorPanel);
            app.ADCMapSlicesSlider.Limits = [1 200];
            app.ADCMapSlicesSlider.MajorTicks = [];
            app.ADCMapSlicesSlider.ValueChangedFcn = createCallbackFcn(app, @ADCMapSlicesSliderValueChanged, true);
            app.ADCMapSlicesSlider.MinorTicks = [];
            app.ADCMapSlicesSlider.Value = 1;
            app.SliceEditField_2Label = uilabel(app.ImageSelectorPanel);
            app.SliceEditField_2Label.Text = 'Slice';
            app.ADCMapSlicesEditField = uieditfield(app.ImageSelectorPanel, 'numeric');
            app.ADCMapSlicesEditField.Limits = [1 Inf];
            app.ADCMapSlicesEditField.ValueDisplayFormat = '%.0f';
            app.ADCMapSlicesEditField.ValueChangedFcn = createCallbackFcn(app, @ADCMapSlicesEditFieldValueChanged, true);
            app.ADCMapSlicesEditField.Value = 1;
            app.ColorMapButtonGroup = uibuttongroup(app.ADCmapTab);
            app.ColorMapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColorMapButtonGroupSelectionChanged2, true);
            app.ColorMapButtonGroup.Title = 'Color Map';
            app.grayButton = uiradiobutton(app.ColorMapButtonGroup);
            app.grayButton.Text = 'gray';
            app.grayButton.Value = true;
            app.autumnButton = uiradiobutton(app.ColorMapButtonGroup);
            app.autumnButton.Text = 'autumn';
            app.winterButton = uiradiobutton(app.ColorMapButtonGroup);
            app.winterButton.Text = 'winter';
            app.parulaButton = uiradiobutton(app.ColorMapButtonGroup);
            app.parulaButton.Text = 'parula';
            app.hotButton = uiradiobutton(app.ColorMapButtonGroup);
            app.hotButton.Text = 'hot';
            app.ADCScaleEditField = uieditfield(app.ColorMapButtonGroup, 'numeric');
            app.ADCScaleEditField.Limits = [0.1 Inf];
            app.ADCScaleEditField.ValueDisplayFormat = '%.2f';
            app.ADCScaleEditField.ValueChangedFcn = createCallbackFcn(app, @ADCScaleEditFieldValueChanged, true);
            app.ADCScaleEditField.Value = 1;
            app.ADCScaleMaxLabel = uilabel(app.ColorMapButtonGroup);
            app.ADCScaleMaxLabel.Text = 'Max';
            app.ADCScaleAutoButton = uibutton(app.ColorMapButtonGroup, 'push');
            app.ADCScaleAutoButton.ButtonPushedFcn = createCallbackFcn(app, @ADCScaleAutoButtonPushed, true);
            app.ADCScaleAutoButton.Text = 'Auto';
            app.ADCImage = uiimage(app.ADCmapTab);
            app.ADCImage.ImageClickedFcn = createCallbackFcn(app, @ADCImageClicked, true);
            app.XEditFieldLabel = uilabel(app.ADCmapTab);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.ADCmapTab, 'numeric');
            app.XEditField.ValueDisplayFormat = '%.0f';
            app.XEditField.Editable = 'off';
            app.YEditFieldLabel = uilabel(app.ADCmapTab);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.ADCmapTab, 'numeric');
            app.YEditField.ValueDisplayFormat = '%.0f';
            app.YEditField.Editable = 'off';
            app.ADCViewField = uieditfield(app.ADCmapTab, 'numeric');
            app.ADCViewField.ValueDisplayFormat = '%.2f';
            app.ADCViewField.Editable = 'off';
            app.ADCLabel = uilabel(app.ADCmapTab);
            app.ADCLabel.Text = 'ADC ';
            app.ADCunitLabel1 = uilabel(app.ADCmapTab);
            app.ADCunitLabel1.Text = '10  mm /s';
            app.ADCunitLabel2 = uilabel(app.ADCmapTab);
            app.ADCunitLabel2.Text = '-3';
            app.ADCunitLabel3 = uilabel(app.ADCmapTab);
            app.ADCunitLabel3.Text = '2';
            app.M0R2Tab = uitab(app.TabGroup3);
            app.M0R2Tab.Title = 'M0 & R^2';
            app.M0MapFig = uiaxes(app.M0R2Tab);
            app.M0MapFig.XTick = [];
            app.M0MapFig.YTick = [];
            app.R2MapFig = uiaxes(app.M0R2Tab);
            app.R2MapFig.XTick = [];
            app.R2MapFig.YTick = [];
            app.M0MapLabel = uilabel(app.M0R2Tab);
            app.M0MapLabel.Text = 'M0 map';
            app.R2MapLabel = uilabel(app.M0R2Tab);
            app.R2MapLabel.Text = 'R^2 map';
            app.ImageSelectorPanel2 = uipanel(app.M0R2Tab);
            app.ImageSelectorPanel2.Title = 'Image Selector';
            app.ADCMapSlicesSlider2 = uislider(app.ImageSelectorPanel2);
            app.ADCMapSlicesSlider2.Limits = [1 200];
            app.ADCMapSlicesSlider2.MajorTicks = [];
            app.ADCMapSlicesSlider2.ValueChangedFcn = createCallbackFcn(app, @ADCMapSlicesSlider2ValueChanged, true);
            app.ADCMapSlicesSlider2.MinorTicks = [];
            app.ADCMapSlicesSlider2.Value = 1;
            app.SliceEditField_2Label_2 = uilabel(app.ImageSelectorPanel2);
            app.SliceEditField_2Label_2.Text = 'Slice';
            app.ADCMapSlicesEditField2 = uieditfield(app.ImageSelectorPanel2, 'numeric');
            app.ADCMapSlicesEditField2.Limits = [1 Inf];
            app.ADCMapSlicesEditField2.ValueDisplayFormat = '%.0f';
            app.ADCMapSlicesEditField2.ValueChangedFcn = createCallbackFcn(app, @ADCMapSlicesEditField2ValueChanged, true);
            app.ADCMapSlicesEditField2.Value = 1;
            app.ColorMapButtonGroup2 = uibuttongroup(app.M0R2Tab);
            app.ColorMapButtonGroup2.SelectionChangedFcn = createCallbackFcn(app, @ColorMapButtonGroup2SelectionChanged, true);
            app.ColorMapButtonGroup2.Title = 'Color Map';
            app.grayButton2 = uiradiobutton(app.ColorMapButtonGroup2);
            app.grayButton2.Text = 'gray';
            app.grayButton2.Value = true;
            app.autumnButton2 = uiradiobutton(app.ColorMapButtonGroup2);
            app.autumnButton2.Text = 'autumn';
            app.winterButton2 = uiradiobutton(app.ColorMapButtonGroup2);
            app.winterButton2.Text = 'winter';
            app.parulaButton2 = uiradiobutton(app.ColorMapButtonGroup2);
            app.parulaButton2.Text = 'parula';
            app.hotButton2 = uiradiobutton(app.ColorMapButtonGroup2);
            app.hotButton2.Text = 'hot';
            app.ColorMapButtonGroup3 = uibuttongroup(app.M0R2Tab);
            app.ColorMapButtonGroup3.SelectionChangedFcn = createCallbackFcn(app, @ColorMapButtonGroup3SelectionChanged, true);
            app.ColorMapButtonGroup3.Title = 'Color Map';
            app.grayButton3 = uiradiobutton(app.ColorMapButtonGroup3);
            app.grayButton3.Text = 'gray';
            app.grayButton3.Value = true;
            app.autumnButton3 = uiradiobutton(app.ColorMapButtonGroup3);
            app.autumnButton3.Text = 'autumn';
            app.winterButton3 = uiradiobutton(app.ColorMapButtonGroup3);
            app.winterButton3.Text = 'winter';
            app.parulaButton3 = uiradiobutton(app.ColorMapButtonGroup3);
            app.parulaButton3.Text = 'parula';
            app.hotButton3 = uiradiobutton(app.ColorMapButtonGroup3);
            app.hotButton3.Text = 'hot';
            app.M0Image = uiimage(app.M0R2Tab);
            app.R2Image = uiimage(app.M0R2Tab);
            app.TabGroup2 = uitabgroup(app.ADCmapUIFigure);
            app.RawimagesTab = uitab(app.TabGroup2);
            app.RawimagesTab.Title = 'Raw images';
            app.RawDataFig = uiaxes(app.RawimagesTab);
            app.RawDataFig.XTick = [];
            app.RawDataFig.YTick = [];
            app.ImageLabel = uilabel(app.RawimagesTab);
            app.ImageLabel.Text = 'Image';
            app.ImageselectorPanel = uipanel(app.RawimagesTab);
            app.ImageselectorPanel.Title = 'Image selector';
            app.SlicesSlider = uislider(app.ImageselectorPanel);
            app.SlicesSlider.Limits = [1 200];
            app.SlicesSlider.MajorTicks = [];
            app.SlicesSlider.ValueChangedFcn = createCallbackFcn(app, @SlicesSliderValueChanged, true);
            app.SlicesSlider.MinorTicks = [];
            app.SlicesSlider.Value = 1;
            app.ImageSlider = uislider(app.ImageselectorPanel);
            app.ImageSlider.Limits = [1 200];
            app.ImageSlider.MajorTicks = [];
            app.ImageSlider.ValueChangedFcn = createCallbackFcn(app, @ImageSliderValueChanged, true);
            app.ImageSlider.MinorTicks = [];
            app.ImageSlider.Value = 1;
            app.SliceEditFieldLabel = uilabel(app.ImageselectorPanel);
            app.SliceEditFieldLabel.Text = 'Slice';
            app.SlicesEditField = uieditfield(app.ImageselectorPanel, 'numeric');
            app.SlicesEditField.Limits = [1 Inf];
            app.SlicesEditField.ValueDisplayFormat = '%.0f';
            app.SlicesEditField.ValueChangedFcn = createCallbackFcn(app, @SlicesEditFieldValueChanged, true);
            app.SlicesEditField.Value = 1;
            app.ImageLabel_2 = uilabel(app.ImageselectorPanel);
            app.ImageLabel_2.Text = 'Image';
            app.ImageEditField = uieditfield(app.ImageselectorPanel, 'numeric');
            app.ImageEditField.Limits = [1 Inf];
            app.ImageEditField.ValueDisplayFormat = '%.0f';
            app.ImageEditField.ValueChangedFcn = createCallbackFcn(app, @SlicesEditFieldValueChanged, true);
            app.ImageEditField.Value = 1;
            app.SegmentationPanel = uipanel(app.RawimagesTab);
            app.SegmentationPanel.Title = 'Segmentation';
            app.ThresholdSlider = uislider(app.SegmentationPanel);
            app.ThresholdSlider.Limits = [1 16384];
            app.ThresholdSlider.MajorTicks = [];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ThresholdSlider.MinorTicks = [];
            app.ThresholdSlider.Value = 1;
            app.ErodeButton = uibutton(app.SegmentationPanel, 'push');
            app.ErodeButton.ButtonPushedFcn = createCallbackFcn(app, @ErodeButtonPushed, true);
            app.ErodeButton.Text = 'Erode';
            app.DilateButton = uibutton(app.SegmentationPanel, 'push');
            app.DilateButton.ButtonPushedFcn = createCallbackFcn(app, @DilateButtonPushed, true);
            app.DilateButton.Text = 'Dilate';
            app.CloseButton = uibutton(app.SegmentationPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.OpenButton = uibutton(app.SegmentationPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.ThresholdEditFieldLabel = uilabel(app.SegmentationPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.SegmentationPanel, 'numeric');
            app.ThresholdEditField.Limits = [0 Inf];
            app.ThresholdEditField.ValueDisplayFormat = '%.0f';
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ImageIntensityPanel = uipanel(app.RawimagesTab);
            app.ImageIntensityPanel.Title = 'Image Intensity';
            app.ScaleSliderLabel = uilabel(app.ImageIntensityPanel);
            app.ScaleSliderLabel.Text = 'Scale';
            app.ScaleSlider = uislider(app.ImageIntensityPanel);
            app.ScaleSlider.Limits = [0 2];
            app.ScaleSlider.MajorTicks = [0 1 2];
            app.ScaleSlider.MajorTickLabels = {''};
            app.ScaleSlider.ValueChangedFcn = createCallbackFcn(app, @ScaleSliderValueChanged, true);
            app.ScaleSlider.Value = 0.01;
            app.RawImage = uiimage(app.RawimagesTab);
            app.BvalueEditFieldLabel = uilabel(app.RawimagesTab);
            app.BvalueEditFieldLabel.Text = 'B-value';
            app.BvalueViewField = uieditfield(app.RawimagesTab, 'text');
            app.GradEditFieldLabel = uilabel(app.RawimagesTab);
            app.GradEditFieldLabel.Text = 'Grad';
            app.GradViewField = uieditfield(app.RawimagesTab, 'text');
            app.FitparametersTab = uitab(app.TabGroup2);
            app.FitparametersTab.Title = 'Fit parameters';
            app.SliceselectionPanel = uipanel(app.FitparametersTab);
            app.SliceselectionPanel.Title = 'Slice selection';
            app.FirstsliceLabel = uilabel(app.SliceselectionPanel);
            app.FirstsliceLabel.Text = 'First slice';
            app.FirstSliceEditField = uieditfield(app.SliceselectionPanel, 'numeric');
            app.FirstSliceEditField.Limits = [1 200];
            app.FirstSliceEditField.ValueChangedFcn = createCallbackFcn(app, @FirstSliceEditFieldValueChanged, true);
            app.FirstSliceEditField.Enable = 'off';
            app.FirstSliceEditField.Value = 1;
            app.LastsliceLabel = uilabel(app.SliceselectionPanel);
            app.LastsliceLabel.Text = 'Last slice';
            app.LastSliceEditField = uieditfield(app.SliceselectionPanel, 'numeric');
            app.LastSliceEditField.Limits = [1 200];
            app.LastSliceEditField.ValueChangedFcn = createCallbackFcn(app, @FirstSliceEditFieldValueChanged, true);
            app.LastSliceEditField.Enable = 'off';
            app.LastSliceEditField.Value = 1;
            app.GoodnessoffitPanel = uipanel(app.FitparametersTab);
            app.GoodnessoffitPanel.Title = 'Goodness of fit';
            app.RsquareLabel = uilabel(app.GoodnessoffitPanel);
            app.RsquareLabel.Text = 'R-square';
            app.Rsquare = uieditfield(app.GoodnessoffitPanel, 'numeric');
            app.Rsquare.Limits = [0 1];
            app.Rsquare.ValueDisplayFormat = '%.2f';
            app.Rsquare.Enable = 'off';
            app.Rsquare.Value = 0.8;
            app.ThresholdR = uicheckbox(app.GoodnessoffitPanel);
            app.ThresholdR.Enable = 'off';
            app.ThresholdR.Text = '';
            app.ThresholdLabel = uilabel(app.GoodnessoffitPanel);
            app.ThresholdLabel.Text = 'Threshold';
            app.BvaluegradientdirectionselectionPanel = uipanel(app.FitparametersTab);
            app.BvaluegradientdirectionselectionPanel.Title = 'B-value / gradient direction selection';
            app.CheckBox_1 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_1.Text = '1';
            app.CheckBox_1.Value = true;
            app.CheckBox_2 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_2.Text = '2';
            app.CheckBox_2.Value = true;
            app.CheckBox_3 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_3.Text = '3';
            app.CheckBox_3.Value = true;
            app.CheckBox_4 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_4.Text = '4';
            app.CheckBox_4.Value = true;
            app.CheckBox_5 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_5.Text = '5';
            app.CheckBox_5.Value = true;
            app.CheckBox_6 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_6.Text = '6';
            app.CheckBox_6.Value = true;
            app.CheckBox_7 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_7.Text = '7';
            app.CheckBox_7.Value = true;
            app.CheckBox_8 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_8.Text = '8';
            app.CheckBox_8.Value = true;
            app.CheckBox_9 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_9.Text = '9';
            app.CheckBox_9.Value = true;
            app.CheckBox_10 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_10.Text = '10';
            app.CheckBox_10.Value = true;
            app.CheckBox_11 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_11.Text = '11';
            app.CheckBox_11.Value = true;
            app.CheckBox_12 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_12.Text = '12';
            app.CheckBox_12.Value = true;
            app.CheckBox_13 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_13.Text = '13';
            app.CheckBox_13.Value = true;
            app.CheckBox_14 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_14.Text = '14';
            app.CheckBox_14.Value = true;
            app.CheckBox_15 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_15.Text = '15';
            app.CheckBox_15.Value = true;
            app.CheckBox_16 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_16.Text = '16';
            app.CheckBox_16.Value = true;
            app.CheckBox_17 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_17.Text = '17';
            app.CheckBox_17.Value = true;
            app.CheckBox_18 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_18.Text = '18';
            app.CheckBox_18.Value = true;
            app.CheckBox_19 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_19.Text = '19';
            app.CheckBox_19.Value = true;
            app.CheckBox_20 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_20.Text = '20';
            app.CheckBox_20.Value = true;
            app.CheckBox_21 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_21.Text = '21';
            app.CheckBox_21.Value = true;
            app.CheckBox_22 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_22.Text = '22';
            app.CheckBox_22.Value = true;
            app.CheckBox_23 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_23.Text = '23';
            app.CheckBox_23.Value = true;
            app.CheckBox_24 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_24.Text = '24';
            app.CheckBox_24.Value = true;
            app.CheckBox_25 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_25.Text = '25';
            app.CheckBox_25.Value = true;
            app.CheckBox_26 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_26.Text = '26';
            app.CheckBox_26.Value = true;
            app.CheckBox_27 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_27.Text = '27';
            app.CheckBox_27.Value = true;
            app.CheckBox_28 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_28.Text = '28';
            app.CheckBox_28.Value = true;
            app.CheckBox_29 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_29.Text = '29';
            app.CheckBox_29.Value = true;
            app.CheckBox_30 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_30.Text = '30';
            app.CheckBox_30.Value = true;
            app.CheckBox_31 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_31.Text = '31';
            app.CheckBox_31.Value = true;
            app.CheckBox_32 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_32.Text = '32';
            app.CheckBox_32.Value = true;
            app.CheckBox_33 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_33.Text = '33';
            app.CheckBox_33.Value = true;
            app.CheckBox_34 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_34.Text = '34';
            app.CheckBox_34.Value = true;
            app.CheckBox_35 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_35.Text = '35';
            app.CheckBox_35.Value = true;
            app.CheckBox_36 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_36.Text = '36';
            app.CheckBox_36.Value = true;
            app.CheckBox_37 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_37.Text = '37';
            app.CheckBox_37.Value = true;
            app.CheckBox_38 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_38.Text = '38';
            app.CheckBox_38.Value = true;
            app.CheckBox_39 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_39.Text = '39';
            app.CheckBox_39.Value = true;
            app.CheckBox_40 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_40.Text = '40';
            app.CheckBox_40.Value = true;
            app.CheckBox_41 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_41.Text = '41';
            app.CheckBox_41.Value = true;
            app.CheckBox_42 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_42.Text = '42';
            app.CheckBox_42.Value = true;
            app.CheckBox_43 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_43.Text = '43';
            app.CheckBox_43.Value = true;
            app.CheckBox_44 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_44.Text = '44';
            app.CheckBox_44.Value = true;
            app.CheckBox_45 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_45.Text = '45';
            app.CheckBox_45.Value = true;
            app.CheckBox_46 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_46.Text = '46';
            app.CheckBox_46.Value = true;
            app.CheckBox_47 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_47.Text = '47';
            app.CheckBox_47.Value = true;
            app.CheckBox_48 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_48.Text = '48';
            app.CheckBox_48.Value = true;
            app.CheckBox_49 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_49.Text = '49';
            app.CheckBox_49.Value = true;
            app.CheckBox_50 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_50.Text = '50';
            app.CheckBox_50.Value = true;
            app.CheckBox_51 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_51.Text = '51';
            app.CheckBox_51.Value = true;
            app.CheckBox_52 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_52.Text = '52';
            app.CheckBox_52.Value = true;
            app.CheckBox_53 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_53.Text = '53';
            app.CheckBox_53.Value = true;
            app.CheckBox_54 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_54.Text = '54';
            app.CheckBox_54.Value = true;
            app.CheckBox_55 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_55.Text = '55';
            app.CheckBox_55.Value = true;
            app.CheckBox_56 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_56.Text = '56';
            app.CheckBox_56.Value = true;
            app.CheckBox_57 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_57.Text = '57';
            app.CheckBox_57.Value = true;
            app.CheckBox_58 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_58.Text = '58';
            app.CheckBox_58.Value = true;
            app.CheckBox_59 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_59.Text = '59';
            app.CheckBox_59.Value = true;
            app.CheckBox_60 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_60.Text = '60';
            app.CheckBox_60.Value = true;
            app.CheckBox_61 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_61.Text = '61';
            app.CheckBox_61.Value = true;
            app.CheckBox_62 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_62.Text = '62';
            app.CheckBox_62.Value = true;
            app.CheckBox_63 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_63.Text = '63';
            app.CheckBox_63.Value = true;
            app.CheckBox_64 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_64.Text = '64';
            app.CheckBox_64.Value = true;
            app.UsergradienttablePanel = uipanel(app.FitparametersTab);
            app.UsergradienttablePanel.Title = 'User gradient table';
            app.ImportGradTableButton = uibutton(app.UsergradienttablePanel, 'push');
            app.ImportGradTableButton.ButtonPushedFcn = createCallbackFcn(app, @ImportGradTableButtonPushed, true);
            app.ImportGradTableButton.Text = 'Import';
            app.TabGroup4 = uitabgroup(app.ADCmapUIFigure);
            app.SequenceparametersTab = uitab(app.TabGroup4);
            app.SequenceparametersTab.Title = 'Sequence parameters';
            app.TRmsEditFieldLabel = uilabel(app.SequenceparametersTab);
            app.TRmsEditFieldLabel.Text = 'TR (ms)';
            app.TRViewField = uieditfield(app.SequenceparametersTab, 'numeric');
            app.TRViewField.Limits = [0 Inf];
            app.TRViewField.Editable = 'off';
            app.TEmsLabel = uilabel(app.SequenceparametersTab);
            app.TEmsLabel.Text = 'TE (ms)';
            app.TEViewField = uieditfield(app.SequenceparametersTab, 'numeric');
            app.TEViewField.Limits = [0 Inf];
            app.TEViewField.ValueChangedFcn = createCallbackFcn(app, @TEViewFieldValueChanged, true);
            app.TEViewField.Editable = 'off';
            app.dimxEditFieldLabel = uilabel(app.SequenceparametersTab);
            app.dimxEditFieldLabel.Text = 'dimx';
            app.dimxViewField = uieditfield(app.SequenceparametersTab, 'numeric');
            app.dimxViewField.Limits = [0 Inf];
            app.dimxViewField.ValueDisplayFormat = '%.0f';
            app.dimxViewField.Editable = 'off';
            app.dimyEditFieldLabel = uilabel(app.SequenceparametersTab);
            app.dimyEditFieldLabel.Text = 'dimy';
            app.dimyViewField = uieditfield(app.SequenceparametersTab, 'numeric');
            app.dimyViewField.Limits = [0 Inf];
            app.dimyViewField.ValueDisplayFormat = '%.0f';
            app.dimyViewField.Editable = 'off';
            app.SlicesEditFieldLabel = uilabel(app.SequenceparametersTab);
            app.SlicesEditFieldLabel.Text = 'Slices';
            app.SlicesViewField = uieditfield(app.SequenceparametersTab, 'numeric');
            app.SlicesViewField.Limits = [0 Inf];
            app.SlicesViewField.ValueDisplayFormat = '%.0f';
            app.SlicesViewField.Editable = 'off';
            app.ImagesLabel = uilabel(app.SequenceparametersTab);
            app.ImagesLabel.Text = 'Images';
            app.NRImagesViewField = uieditfield(app.SequenceparametersTab, 'numeric');
            app.NRImagesViewField.Limits = [0 Inf];
            app.NRImagesViewField.Editable = 'off';
            app.FOVmmEditFieldLabel = uilabel(app.SequenceparametersTab);
            app.FOVmmEditFieldLabel.Text = 'FOV (mm)';
            app.FOVViewField = uieditfield(app.SequenceparametersTab, 'numeric');
            app.FOVViewField.Limits = [0 Inf];
            app.FOVViewField.Editable = 'off';
            app.SlicethicknessmmLabel = uilabel(app.SequenceparametersTab);
            app.SlicethicknessmmLabel.Text = 'Slice thickness (mm)';
            app.SLTViewField = uieditfield(app.SequenceparametersTab, 'numeric');
            app.SLTViewField.Limits = [0 Inf];
            app.SLTViewField.Editable = 'off';
            app.AspectratioLabel = uilabel(app.SequenceparametersTab);
            app.AspectratioLabel.Text = 'Aspect ratio';
            app.AspectRatioViewField = uieditfield(app.SequenceparametersTab, 'numeric');
            app.AspectRatioViewField.Limits = [0.25 4];
            app.AspectRatioViewField.ValueDisplayFormat = '%.1f';
            app.AspectRatioViewField.Editable = 'off';
            app.AspectRatioViewField.Value = 1;
            app.DicomdirEditFieldLabel = uilabel(app.SequenceparametersTab);
            app.DicomdirEditFieldLabel.Text = 'Dicom dir';
            app.DicomDirViewField = uieditfield(app.SequenceparametersTab, 'text');
            app.DicomDirViewField.Editable = 'off';
            % Show the figure after all components are created
            app.ADCmapUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ADCmapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ADCmapUIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ADCmapUIFigure)
        end
    end
end